% description: Copyright 2017-2020 Luca Della Santina
% Licensed under the term of GPL v3
classdef VolumeCut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VolumeCutUIFigure            matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        CutTab                       matlab.ui.container.Tab
        btnCutVolume                 matlab.ui.control.Button
        btnLoadImage                 matlab.ui.control.Button
        btnSaveImage                 matlab.ui.control.Button
        btnRefPoints                 matlab.ui.control.Button
        pnlCuttersettings            matlab.ui.container.Panel
        Offset1Label                 matlab.ui.control.Label
        offset1                      matlab.ui.control.Spinner
        Offset2Label                 matlab.ui.control.Label
        offset2                      matlab.ui.control.Spinner
        lblSmoothing                 matlab.ui.control.Label
        smoothing                    matlab.ui.control.Spinner
        Offset3Label                 matlab.ui.control.Label
        offset3                      matlab.ui.control.Spinner
        btnsCuttingMode              matlab.ui.container.ButtonGroup
        btnCutModeThreeParts         matlab.ui.control.ToggleButton
        btnCutModeFourParts          matlab.ui.control.ToggleButton
        btnCutModeSurfMidSurf        matlab.ui.control.ToggleButton
        btnCutModeTwoParts           matlab.ui.control.ToggleButton
        btnFitSurface                matlab.ui.control.Button
        RenderingPanel               matlab.ui.container.Panel
        btnPreview                   matlab.ui.control.Button
        volumeToRender               matlab.ui.control.DropDown
        btnRedraw                    matlab.ui.control.Button
        lblIname                     matlab.ui.control.Label
        lblPts1name                  matlab.ui.control.Label
        lblPts2name                  matlab.ui.control.Label
        lblPts3name                  matlab.ui.control.Label
        cmbCalibrated                matlab.ui.control.DropDown
        UIAxes                       matlab.ui.control.UIAxes
        MaskTab                      matlab.ui.container.Tab
        btnLoadMask                  matlab.ui.control.Button
        btnSaveMask                  matlab.ui.control.Button
        BetweenMaskIIIPanel          matlab.ui.container.Panel
        btnIntersect                 matlab.ui.control.Button
        btnUnite                     matlab.ui.control.Button
        btnSubtract                  matlab.ui.control.Button
        btnExclude                   matlab.ui.control.Button
        btnsVisualizeMask            matlab.ui.container.ButtonGroup
        btnDrawMask3                 matlab.ui.control.ToggleButton
        btnDrawMask1                 matlab.ui.control.ToggleButton
        btnDrawMask2                 matlab.ui.control.ToggleButton
        Zslider                      matlab.ui.control.Slider
        ResultmaskoperationsPanel    matlab.ui.container.Panel
        btnRevert                    matlab.ui.control.Button
        btnContour                   matlab.ui.control.Button
        btnInvert                    matlab.ui.control.Button
        btnClose                     matlab.ui.control.Button
        btnErode                     matlab.ui.control.Button
        btnDilate                    matlab.ui.control.Button
        btnFill                      matlab.ui.control.Button
        btnSkel                      matlab.ui.control.Button
        btnUltierode                 matlab.ui.control.Button
        btnMedian                    matlab.ui.control.Button
        btnMaskInfo                  matlab.ui.control.Button
        UIAxesMask                   matlab.ui.control.UIAxes
        SkeletonTab                  matlab.ui.container.Tab
        btnLoadSkel                  matlab.ui.control.Button
        btnSkel2Mask                 matlab.ui.control.Button
        btnSkelSave                  matlab.ui.control.Button
        btnSkelReflectX              matlab.ui.control.Button
        btnSkelReflectY              matlab.ui.control.Button
        btnSkelReflectZ              matlab.ui.control.Button
        btnSkelReset                 matlab.ui.control.Button
        btnSkelInfo                  matlab.ui.control.Button
        btnSkelRecalibrate           matlab.ui.control.Button
        btnSkelResize                matlab.ui.control.Button
        btnSkelBinary                matlab.ui.control.Button
        UIAxesSkel                   matlab.ui.control.UIAxes
        AutomateTab                  matlab.ui.container.Tab
        ProcessmultiplemasksPanel    matlab.ui.container.Panel
        btnBatchFolder               matlab.ui.control.Button
        btnBatchProcess              matlab.ui.control.Button
        chkBatchSubfolders           matlab.ui.control.CheckBox
        MaskimagesLabel              matlab.ui.control.Label
        txtBatchMaskFilesPattern     matlab.ui.control.EditField
        BatchoperationDropDownLabel  matlab.ui.control.Label
        cmbBatchOperation            matlab.ui.control.DropDown
        RawimagesLabel               matlab.ui.control.Label
        txtBatchRawFilesPattern      matlab.ui.control.EditField
        AboutTab                     matlab.ui.container.Tab
        lblAppName                   matlab.ui.control.Label
        lblVersion                   matlab.ui.control.Label
        Copyright20172020LucaDellaSantinaLabel  matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        Image                        matlab.ui.control.Image
    end

    
    properties (Access = private)
        Version = '6.0.2' % App version
        Isrc  % Source image
        Ires  % Source image resolution [X, Y, Z]
        Isize % Source image size [X, Y, Z]
        Ipath % PathName to the image file
        Iname % FileName of the image gile
        Pts1  = [] % First set of reference cut points
        Pts1name % Name of first set of points
        Pts2name % Name of second set of points
        Pts3name % Name of third set of points
        Pts2  = [] % Second set of reference cut points
        Pts3  = [] % Third set of reference cut points
        g1 = [] % First interpolated surface
        g2 = [] % Second interpolated surface
        g3 = [] % Third (Middle between g1 and g2) interpolated surface
        s1 = [] % Fitted surface 1
        s2 = [] % Fitted surface 2
        s3 = [] % Fitted surface 3
        Calibrated % True if measurements in micron, false if measurements in pixels
        Idst1 % Cut image #1
        Idst2 % Cut image #2
        Idst3 % Cut image #3
        Idst4 % Cut image #4
        Mask1 % Processing mask1
        Mask2 % Processing mask2
        Mask3 % Processing mask3
        Mask4 % Processing mask4
        CutMode = 2 % Modality of cutting [0,1,2,3]
        Skel  % Skeleton (original from file)
        SkelResult % Modified Skeleton
        SkelPath % Path to the skeleton file
    end
    
    methods (Access = private)
        
        function plotSkeleton(app)
            % Draw maximum intensity projections on the walls of the volume  
            cla(app.UIAxesSkel);
            
            % Plot each branch of the skeleton as a continuous line
            for i = 1: numel(app.SkelResult.branches)                
                plot3(app.UIAxesSkel,...
                      app.SkelResult.branches(i).points(:,1),...
                      app.SkelResult.branches(i).points(:,2), ...
                      app.SkelResult.branches(i).points(:,3),...
                      'Color',[0,0.5,0.5], 'linestyle','-', 'LineWidth', 3); 
                if i==1
                    hold(app.UIAxesSkel, 'on');
                end
            end
            
            % Plot skeleton origin location as circle (usually soma location)
            scatter3(app.UIAxesSkel,...
                     app.SkelResult.branches(1).points(1,1),...
                     app.SkelResult.branches(1).points(1,2),...
                     app.SkelResult.branches(1).points(1,3),...
                     'filled', 'o', 'LineWidth',3, 'MarkerEdgeColor',[0.5, 0, 0], 'MarkerFaceColor',[0.9,0,0]);
            
            % Adjust scales         
            shading(app.UIAxesSkel, 'interp');
            xlabel(app.UIAxesSkel, 'X');
            ylabel(app.UIAxesSkel, 'Y');
            xlim(app.UIAxesSkel, [0 app.SkelResult.imgsize.width]);
            ylim(app.UIAxesSkel, [0 app.SkelResult.imgsize.height]);
            zlim(app.UIAxesSkel, [0 app.SkelResult.imgsize.depth]);            
        end
        
        function Density = calcDensityByDepth(~, Mask)
            Density.zStart = 1;    
            Density.zEnd = size(Mask,3);
            Density.binSize = (Density.zEnd - Density.zStart)/100; % binning densities every 1 percent of Z-depth
            
            if Density.zEnd == Density.zStart
                % 2D image (single Z plane)
                tmpDensity = numel(find(Mask));
                for i = 100:-1:1
                    tmpDensityPerc(i) = tmpDensity;
                end
            else
                % 3D image (multiple Z planes) 
                tmpDensity = zeros(1, (Density.zEnd - Density.zStart +1));
                tmpDensityPerc = zeros(1, 100);
                for i = Density.zStart: Density.zEnd -1
                    tmpDensity(i) = numel(find(Mask(:,:,i)));
                end
                for i = 1:100
                    tmpDensityPerc(i) = tmpDensity(ceil(i*Density.binSize));
                end
            end
            
            Density.density = tmpDensity;
            Density.densityPerc = tmpDensityPerc;            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Starting VolumeCut...','Indeterminate', 'on');            
            movegui(app.VolumeCutUIFigure,'center');
            pause(0.2);
            app.VolumeCutUIFigure.Name = ['VolumeCut ' app.Version];
            app.lblVersion.Text = ['Version ' app.Version];
            app.VolumeCutUIFigure.WindowScrollWheelFcn = ''; % workaround for loss of interaction with UIAxes components
            app.Calibrated = false;
            app.btnsCuttingModeSelectionChanged;
            close(dlg);
        end

        % Button pushed function: btnPreview
        function btnPreviewPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Rendering volume...','Indeterminate', 'on');
            
            % Render the selected volume
            try
                switch app.volumeToRender.Value
                    case 'Raw Image',   I = app.Isrc;
                    case 'Cut I',       I = app.Idst1;
                    case 'Cut II',      I = app.Idst2;
                    case 'Cut III',     I = app.Idst3;
                    case 'Cut IV',      I = app.Idst4;     
                end    
            catch
                disp('Error rendering the volume, probably ran out of memory.');
                return
            end
            figure('NumberTitle', 'off', 'Name', app.volumeToRender.Value);
            volshow(I, 'BackGroundColor', [0.1 0.1 0.1], 'ScaleFactors', app.Ires./app.Ires(1), 'Renderer', 'MaximumIntensityProjection');

            close(dlg);
        end

        % Button pushed function: btnLoadImage
        function btnLoadImageButtonPushed(app, event)
            [FileName, PathName] = uigetfile('*.tif', 'Select the image to process');
            app.VolumeCutUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.VolumeCutUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus

            if ~FileName
                app.lblIname.Text = 'Image file: N/A';
                return
            else
                cd(PathName);
                app.Ipath = PathName;
                app.Iname = FileName;
                app.lblIname.Text = ['Image file: ' FileName];
            end
            
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Loading image...','Indeterminate', 'on');                                

            % Load image file into memory
            [app.Isrc, app.Isize, app.Ires] = loadImageStack(PathName, FileName);
            app.Isrc = uint8(app.Isrc);
            
            app.btnRedrawButtonPushed;
            
            app.btnRefPoints.Enable     = 'on';
            app.btnSaveImage.Enable     = 'off';
            close(dlg);            
        end

        % Selection changed function: btnsCuttingMode
        function btnsCuttingModeSelectionChanged(app, event)
            selectedButton = app.btnsCuttingMode.SelectedObject;
            switch selectedButton
                case app.btnCutModeTwoParts
                    app.offset1.Enable   = 'on';
                    app.offset2.Enable   = 'off';
                    app.offset3.Enable   = 'off';
                    app.CutMode          = 0;                    
                case app.btnCutModeThreeParts
                    app.offset1.Enable   = 'on';
                    app.offset2.Enable   = 'on';
                    app.offset3.Enable   = 'off';
                    app.CutMode          = 1;                    
                case app.btnCutModeSurfMidSurf
                    app.offset1.Enable   = 'on';
                    app.offset2.Enable   = 'on';
                    app.offset3.Enable = 'on';
                    app.CutMode          = 2;
                case app.btnCutModeFourParts
                    app.offset1.Enable   = 'on';
                    app.offset2.Enable   = 'on';
                    app.offset3.Enable = 'on';
                    app.CutMode          = 3;
            end
        end

        % Button pushed function: btnRefPoints
        function btnRefPointsButtonPushed(app, event)
            switch uiconfirm(app.VolumeCutUIFigure, 'How are reference points defined?','Points','Options', {'Load from files', 'Draw on volume'}, 'DefaultOption', 1, 'CancelOption',1)
                case 'Draw on volume'
                    switch app.CutMode
                        case 0, CutPlanes = 1;
                        case 1, CutPlanes = 2;
                        case 2, CutPlanes = 2;
                        case 3, CutPlanes = 3;
                    end
                    
                    dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Annotating volume...','Indeterminate', 'on');                                
                    for i = 1:CutPlanes
                        dlgTitle = 'Input Name of Points';
                        dlgPrompt = ['Name of reference points #' num2str(i)];
                        dlgDefInput = {['Points' num2str(i)]};
                        dlgDims = [1 40];
                        opts.Interpreter = 'none';
                        Name = inputdlg(dlgPrompt, dlgTitle, dlgDims,dlgDefInput, opts);
                        if isempty(Name)
                            break % User cancelled
                        end
                        
                        D.Name = Name{:};
                        D.Pos = [];
                        D.Vox = [];
                        D.Filter = [];
                        Dots = markVolume2D(app.Isrc,D);
                        switch i
                            case 1 
                                app.Pts1 = double(Dots.Pos);
                                app.Pts1name = Dots.Name;
                                app.lblPts1name.Text = ['Cut Points #1: ' Dots.Name];
                            case 2
                                app.Pts2 = double(Dots.Pos);
                                app.Pts2name = Dots.Name;
                                app.lblPts2name.Text = ['Cut Points #2: ' Dots.Name];
                                
                            case 3, app.Pts3 = double(Dots.Pos);
                                app.Pts3 = double(Dots.Pos);
                                app.Pts3name = Dots.Name;
                                app.lblPts3name.Text = ['Cut Points #3: ' Dots.Name];
                        end
                        
                    end
                    close(dlg);
                case 'Load from files'
                    % Load the points delimiting the upper and lower surfaces, surfaceFit them.
                    [FileName, PathName] = uigetfile('*.mat', 'Select the reference slice points', 'MultiSelect', 'on');
                    app.VolumeCutUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
                    app.VolumeCutUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
                    
                    dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Loading points...','Indeterminate', 'on');                                
                    
                    if iscell(FileName)
                        nbfiles = length(FileName);
                    elseif FileName ~= 0
                        nbfiles = 1;
                    else
                        nbfiles = 0;
                    end
                    
                    % Load cut reference points depending on how many files user provided
                    switch nbfiles
                        case 1
                            Dots = load([PathName FileName]);
                            app.Pts1name                        = FileName;
                            app.Pts1                            = double(Dots.Pos);
                            app.Pts2                            = [];
                            app.Pts3                            = [];
                            app.lblPts1name.Text                = ['Cut Points #1: ' app.Pts1name];
                            app.lblPts2name.Text                = 'Cut Points #2: N/A';
                            app.lblPts3name.Text                = 'Cut Points #3: N/A';
                            app.btnCutModeTwoParts.Enable       = 'on';
                            app.btnCutModeTwoParts.Value        = true;
                            app.btnCutModeThreeParts.Enable     = 'off';
                            app.btnCutModeSurfMidSurf.Enable    = 'off';
                            app.btnCutModeFourParts.Enable      = 'off';
                        case 2
                            Dots = load(fullfile(PathName, FileName{1}));
                            app.Pts1name                        = FileName{1};
                            app.Pts1                            = double(Dots.Pos);
                            Dots = load(fullfile(PathName, FileName{2}));
                            app.Pts2name                        = FileName{2};
                            app.Pts2                            = double(Dots.Pos);
                            app.Pts3                            = [];
                            app.lblPts1name.Text                = ['Cut Points #1: ' app.Pts1name];
                            app.lblPts2name.Text                = ['Cut Points #2: ' app.Pts2name];
                            app.lblPts3name.Text                = 'Cut Points #3: N/A';
                            app.btnCutModeTwoParts.Enable       = 'off';
                            app.btnCutModeThreeParts.Enable     = 'on';
                            app.btnCutModeThreeParts.Value      = true;
                            app.btnCutModeSurfMidSurf.Enable    = 'on';
                            app.btnCutModeFourParts.Enable      = 'off';
                        case 3
                            Dots = load(fullfile(PathName, FileName{1}));
                            app.Pts1                            = double(Dots.Pos);
                            app.Pts1name                        = FileName{1};
                            Dots = load(fullfile(PathName, FileName{2}));
                            app.Pts2name                        = FileName{2};
                            app.Pts2                            = double(Dots.Pos);
                            Dots = load(fullfile(PathName, FileName{3}));
                            app.Pts3name                        = FileName{3};
                            app.Pts3                            = double(Dots.Pos);
                            app.lblPts1name.Text                = ['Cut Points #1: ' app.Pts1name];
                            app.lblPts2name.Text                = ['Cut Points #2: ' app.Pts2name];
                            app.lblPts3name.Text                = ['Cut Points #3: ' app.Pts3name];
                            app.btnCutModeTwoParts.Enable       = 'off';
                            app.btnCutModeThreeParts.Enable     = 'off';
                            app.btnCutModeSurfMidSurf.Enable    = 'off';
                            app.btnCutModeFourParts.Enable      = 'on';
                            app.btnCutModeFourParts.Value       = true;
                        otherwise
                            app.Pts1name                        = '';
                            app.Pts2name                        = '';
                            app.Pts3name                        = '';
                            app.lblPts1name.Text                = 'Cut Points #1: N/A';
                            app.lblPts2name.Text                = 'Cut Points #2: N/A';
                            app.lblPts3name.Text                = 'Cut Points #3: N/A';
                            app.Pts1                            = false;
                            app.Pts2                            = false;
                            app.Pts3                            = false;
                            app.btnCutModeTwoParts.Enable       = 'off';
                            app.btnCutModeThreeParts.Enable     = 'off';
                            app.btnCutModeSurfMidSurf.Enable    = 'off';
                            app.btnCutModeFourParts.Enable      = 'off';
                    end
                    close(dlg);
                app.btnsCuttingModeSelectionChanged;                    
            end
                        
            app.btnRefPoints.Enable     = 'on';
            app.btnFitSurface.Enable    = 'on';
            app.btnCutVolume.Enable     = 'off';
            app.btnSaveImage.Enable     = 'off';
        end

        % Button pushed function: btnFitSurface
        function btnFitSurfaceButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Fitting surfaces','Indeterminate', 'on');                                
            
            %cla(app.UIAxes); % Clear current 3D plot area from previously calculated surfaces
            app.g1 = 0;
            app.g2 = 0;
            app.g3 = 0;
            gx     = 1:2:app.Isize(1);
            gy     = 1:2:app.Isize(2);
            
            if ~isempty(app.Pts1)  
                dlg.Message = {'Surface #1...'};
                tic;
                x      = app.Pts1(:,1);
                y      = app.Pts1(:,2);
                z      = app.Pts1(:,3);
                [app.g1, app.s1] = surfacefit(x,y,z,gx,gy);
                if app.Calibrated
                    app.g1 = uint8(ceil(app.g1)+ceil(app.offset1.Value/app.Ires(3))); % apply um offset to surface
                else
                    app.g1 = uint8(ceil(app.g1)+ceil(app.offset1.Value)); % apply pixel offest to surface
                end
                t1 = toc;
                dlg.Message = {['Surface #1... done in ' num2str(t1) ' seconds']};
            end
            

            if ~isempty(app.Pts2)                
                dlg.Message = {['Surface #1... done in ' num2str(t1) ' seconds'],...
                                'Surface #2...'};
                tic;
                x      = app.Pts2(:,1);
                y      = app.Pts2(:,2);
                z      = app.Pts2(:,3);
                [app.g2, app.s2] = surfacefit(x,y,z,gx,gy);
                if app.Calibrated
                    app.g2 = uint8(ceil(app.g2)+ceil(app.offset2.Value/app.Ires(3))); % apply um offset to surface
                else
                    app.g2 = uint8(ceil(app.g2)+ceil(app.offset2.Value)); % apply px offset to surface
                end
                t2 = toc;
                dlg.Message = {['Surface #1... done in ' num2str(t1) ' seconds'],...
                               ['Surface #2... done in ' num2str(t2) ' seconds']};
            end

            dlg.Message = 'Surface #3';
            if ~isempty(app.Pts3)
                dlg.Message = {['Surface #1... done in ' num2str(t1) ' seconds'],...
                               ['Surface #2... done in ' num2str(t2) ' seconds'],...
                                'Surface #3...'};
                tic;
                x      = app.Pts3(:,1);
                y      = app.Pts3(:,2);
                z      = app.Pts3(:,3);
                [app.g3, app.s3] = surfacefit(x,y,z,gx,gy);
                if app.Calibrated
                    app.g3 = uint8(ceil(app.g3)+ceil(app.offset3.Value/app.Ires(3))); % apply um offset to surface
                else
                    app.g3 = uint8(ceil(app.g3)+ceil(app.offset3.Value)); % apply px offset to surface
                end
                t3 = toc;
                dlg.Message = {['Surface #1... done in ' num2str(t1) ' seconds'],...
                               ['Surface #2... done in ' num2str(t2) ' seconds'],...
                               ['Surface #3... done in ' num2str(t3) ' seconds']};
                
            elseif app.CutMode == 2                
                if app.Calibrated
                    app.g3 = uint8(ceil((app.g2+app.g1)/2)+ceil(app.offset3.Value/app.Ires(3))); % apply um offset to surface
                else
                    app.g3 = uint8(ceil((app.g2+app.g1)/2)+ceil(app.offset3.Value)); % apply px offset to surface
                end
                t3 = toc;
                dlg.Message = {['Surface #1... done in ' num2str(t1) ' seconds'],...
                               ['Surface #2... done in ' num2str(t2) ' seconds'],...
                               ['Surface #3... done in ' num2str(t3) ' seconds']};
            end

            app.btnRedrawButtonPushed;
            
            app.btnRefPoints.Enable     = 'on';
            app.btnFitSurface.Enable    = 'on';
            app.btnCutVolume.Enable     = 'on';
            app.btnSaveImage.Enable     = 'off';

            close(dlg);
        end

        % Button pushed function: btnCutVolume
        function btnCutVolumeButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Cutting volume...','Indeterminate', 'on');
            pause(0.2);

            [app.Idst1, app.Mask1, app.Idst2, app.Mask2, app.Idst3, app.Mask3, app.Idst4, app.Mask4] = cutVolume(app.Isrc, app.CutMode, app.g1, app.g2, app.g3);

            app.btnRefPoints.Enable     = 'on';
            app.btnFitSurface.Enable    = 'on';
            app.btnCutVolume.Enable     = 'on';
            app.btnSaveImage.Enable     = 'on';

            close(dlg);
        end

        % Button pushed function: btnSaveImage
        function btnSaveImageButtonPushed(app, event)
            S.Part1 = 1;
            S.Part2 = 1;
            S.Part3 = 1;
            S.Part4 = 1;
            S.SlicedVolumes = 1;
            S.Masks = 1;
            S = editStruct(S);            
            if isempty(S)
                return
            end
            
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Saving volumes...','Indeterminate', 'on');
            
            % Save resulting halves of the image I into I1.tif and I2.tif
            [PathName,FileName,ext] = fileparts(fullfile(app.Ipath, app.Iname));
            option.overwrite = true;
            
            if S.Part1
                if S.SlicedVolumes
                    fprintf('Saving sliced volume -part1 ... ');
                    saveastiff(app.Idst1, [PathName filesep FileName '-part1' ext], option);
                end
                if S.Masks
                    fprintf('Saving mask used for sliced volume -part1 ... ');
                    saveastiff(uint8(app.Mask1), [PathName filesep FileName '-part1mask' ext], option);
                end
            end
            
            if S.Part2
                if S.SlicedVolumes
                    fprintf('Saving sliced volume -part2 ... ');
                    saveastiff(app.Idst2, [PathName filesep FileName '-part2' ext], option);
                end
                if S.Masks
                    fprintf('Saving mask used for sliced volume -part2 ... ');
                    saveastiff(uint8(app.Mask2), [PathName filesep FileName '-part2mask' ext], option);

                end
            end
            
            if S.Part3 && ((app.CutMode == 1) || (app.CutMode == 3))
                if S.SlicedVolumes
                    fprintf('Saving sliced volume -part3 ... ');
                    saveastiff(app.Idst3, [PathName filesep FileName '-part3' ext], option);
                end
                if S.Masks
                    fprintf('Saving mask used for sliced volume -part3 ... ');
                    saveastiff(uint8(app.Mask3), [PathName filesep FileName '-part3mask' ext], option);
                end
            end
                       
            if app.CutMode == 3 && S.Part4
                if S.SlicedVolumes
                    fprintf('Saving sliced volume -part3 ... ');
                    saveastiff(app.Idst4, [PathName filesep FileName '-part4' ext], option);
                end
                if S.Masks
                    fprintf('Saving mask used for sliced volume -part4 ... ');
                    saveastiff(uint8(app.Mask4), [PathName filesep FileName '-part4mask' ext], option);
                end
            end            

            close(dlg);
        end

        % Button pushed function: btnRedraw
        function btnRedrawButtonPushed(app, event)
            % Draw maximum intensity projections on the walls of the volume  
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Plotting data...','Indeterminate', 'on');                                

            cla(app.UIAxes);
            
            % Define a custom colormap depending on how many objects will be drawn
            % MIPs=Gray Surf1=Winter Surf2=Summer Surf3=Autumn
            if ~isempty(app.g2) && numel(app.g2)>1
                colormap(app.UIAxes, [gray(256); winter(256); summer(256); autumn(256)]);
            elseif ~isempty(app.g1) && numel(app.g1)>1
                colormap(app.UIAxes, [gray(256); winter(256)]);
            else
                colormap(app.UIAxes, gray(256));
            end
            hold(app.UIAxes, 'on');
            view(app.UIAxes, 3); % Display 3 axes
                        
            gx = 1:2:app.Isize(1); % fitted surface is a 2x downsample version of original image
            gy = 1:2:app.Isize(2); % fitted surface is a 2x downsample version of original image
            if ~isempty(app.g1) && numel(app.g1)>1
                Cmin = min(min(double(app.g1)));
                Cmax = max(max(double(app.g1)));
                C = 256+256*(double(app.g1)-Cmin)/(Cmax-Cmin); %CData for surface (stretched)                
                surf(app.UIAxes, gx , gy, app.g1, C);
                shading(app.UIAxes, 'interp');
                line(app.UIAxes, app.Pts1(:,1),app.Pts1(:,2),app.Pts1(:,3),'marker','.','markersize',10,'MarkerEdgeColor',[1 1 0],'linestyle','none')                
            end
            if ~isempty(app.g2) && numel(app.g2)>1
                Cmin = min(min(double(app.g2)));
                Cmax = max(max(double(app.g2)));
                C = 256+256+256+256*(double(app.g2)-Cmin)/(Cmax-Cmin); %CData for surface (stretched)                
                surf(app.UIAxes, gx , gy, app.g2, C);
                shading(app.UIAxes, 'interp');
                line(app.UIAxes, app.Pts2(:,1),app.Pts2(:,2),app.Pts2(:,3),'marker','.','markersize',10, 'MarkerEdgeColor',[0.2 0.8 0.2],'linestyle','none')                
            end
            if ~isempty(app.g3) && numel(app.g3)>1
                Cmin = min(min(double(app.g3)));
                Cmax = max(max(double(app.g3)));
                C = 512+256*(double(app.g3)-Cmin)/(Cmax-Cmin); %CData for surface (stretched)
                surf(app.UIAxes, gx , gy, app.g3, C);
                shading(app.UIAxes, 'interp');
                if numel(app.Pts3)>1
                    line(app.UIAxes, app.Pts3(:,1),app.Pts3(:,2),app.Pts3(:,3),'marker','.','markersize',10, 'MarkerEdgeColor',[1 0 0],'linestyle','none')                
                end
            end
            
            MIPx= squeeze(max(app.Isrc,[],1)); % Create a MIP of each image to display
            Cmin = min(min(double(MIPx)));
            Cmax = max(max(double(MIPx)));
            C = 256*(double(MIPx)-Cmin)/(Cmax-Cmin)-1; %CData for surface (stretched)
            surf(app.UIAxes , [app.Isize(1) app.Isize(1); 1 1],...
                              [app.Isize(2)+2 app.Isize(2)+2; app.Isize(2)+2 app.Isize(2)+2],...
                              [1 app.Isize(3); 1 app.Isize(3)], C,'facecolor','texture');

            MIPy= squeeze(max(app.Isrc,[],2))/2-1; % Create a MIP of each image to display
            Cmin = min(min(double(MIPy)));
            Cmax = max(max(double(MIPy)));
            C = 256*(double(MIPy)-Cmin)/(Cmax-Cmin)-1; %CData for surface (stretched)            
            surf(app.UIAxes , [app.Isize(1)+2 app.Isize(1)+2; app.Isize(1)+2 app.Isize(1)+2],...
                              [app.Isize(2) app.Isize(2); 1 1],...
                              [1 app.Isize(3); 1 app.Isize(3)], C,'facecolor','texture');

            MIPz= squeeze(max(app.Isrc,[],3)); % Create a MIP of each image to display
            Cmin = min(min(double(MIPz)));
            Cmax = max(max(double(MIPz)));
            C = 256*(double(MIPz)-Cmin)/(Cmax-Cmin)-1; %CData for surface (stretched)
            surf(app.UIAxes , [1 app.Isize(1); 1 app.Isize(2)],...
                              [1 1; app.Isize(1) app.Isize(2)],...
                              [-1 -1; -1 -1], C,'facecolor','texture');
            
            xlim(app.UIAxes, [0 app.Isize(1)+2]);
            ylim(app.UIAxes, [0 app.Isize(2)+2]);
            zlim(app.UIAxes, [-1 app.Isize(3)]);            

            if app.Calibrated
                xt = get(app.UIAxes, 'XTick');                              % 'XTick' Values
                set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt*app.Ires(1)); % Relabel 'XTick' With 'XTickLabel' Values
                yt = get(app.UIAxes, 'YTick');                              % 'YTick' Values
                set(app.UIAxes, 'YTick', yt, 'YTickLabel', yt*app.Ires(2)); % Relabel 'YTick' With 'YTickLabel' Values                
                zt = get(app.UIAxes, 'ZTick');                              % 'ZTick' Values
                set(app.UIAxes, 'ZTick', zt, 'ZTickLabel', zt*app.Ires(3)); % Relabel 'ZTick' With 'ZTickLabel' Values
            else
                xt = get(app.UIAxes, 'XTick');                              % 'XTick' Values
                set(app.UIAxes, 'XTick', xt, 'XTickLabel', xt);             % Relabel 'XTick' With 'XTickLabel' Values
                yt = get(app.UIAxes, 'YTick');                              % 'YTick' Values
                set(app.UIAxes, 'YTick', yt, 'YTickLabel', yt);             % Relabel 'YTick' With 'YTickLabel' Values                
                zt = get(app.UIAxes, 'ZTick');                              % 'ZTick' Values
                set(app.UIAxes, 'ZTick', zt, 'ZTickLabel', zt);             % Relabel 'ZTick' With 'ZTickLabel' Values
            end 
            
            close(dlg);
        end

        % Value changed function: cmbCalibrated
        function cmbCalibratedValueChanged(app, event)
            value = app.cmbCalibrated.Value;
            
            switch value
                case 'Px', app.Calibrated = false;
                case 'um', app.Calibrated = true;
            end
            
            app.btnRedrawButtonPushed;
        end

        % Button pushed function: btnLoadMask
        function btnLoadMaskButtonPushed(app, event)
            [FileName, PathName] = uigetfile('*.tif', 'Select masks to process', 'MultiSelect', 'on');
            app.VolumeCutUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.VolumeCutUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus

            if iscell(FileName)
                app.Ipath = PathName;
                cd(PathName);
                nbfiles = length(FileName);
            elseif FileName ~= 0
                app.Ipath = PathName;
                cd(PathName);                
                nbfiles = 1;
            else
                return
            end
                        
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Loading mask...','Indeterminate', 'on');
            
            switch nbfiles
                case 1
                    app.Mask1 = loadImageStack(PathName, FileName);
                    app.Mask1 = logical(app.Mask1);
                    app.Mask3 = app.Mask1;
                    app.btnDrawMask1.Enable = 'on';
                    app.btnDrawMask1.Value  = true;
                    app.btnDrawMask2.Enable = 'off';
                    app.btnDrawMask3.Enable = 'on';
                    app.Zslider.Enable      = 'on';

                    app.btnRevert.Enable    = 'on';
                    app.btnContour.Enable   = 'on';
                    app.btnInvert.Enable    = 'on';
                    app.btnClose.Enable     = 'on';
                    app.btnErode.Enable     = 'on';
                    app.btnDilate.Enable    = 'on';
                    app.btnFill.Enable      = 'on';
                    app.btnSkel.Enable      = 'on';
                    app.btnMedian.Enable    = 'on';
                    app.btnUltierode.Enable = 'on';
                    app.btnIntersect.Enable = 'off';
                    app.btnUnite.Enable     = 'off';
                    app.btnExclude.Enable   = 'off';
                    app.btnSubtract.Enable  = 'off';

                    app.btnSaveMask.Enable  = 'on';  
                    app.btnMaskInfo.Enable  = 'on';
                    app.btnsVisualizeMaskSelectionChanged;
                case 2
                    app.Mask1 = loadImageStack(PathName, FileName{1});
                    app.Mask1 = logical(app.Mask1);
                    app.Mask2 = loadImageStack(PathName, FileName{2});
                    app.Mask2 = logical(app.Mask2);
                    app.Mask3 = app.Mask1;
                    app.btnDrawMask1.Enable = 'on';
                    app.btnDrawMask1.Value  = true;
                    app.btnDrawMask2.Enable = 'on';
                    app.btnDrawMask3.Enable = 'on';
                    app.Zslider.Enable      = 'on';

                    app.btnRevert.Enable    = 'on';
                    app.btnContour.Enable   = 'on';
                    app.btnInvert.Enable    = 'on';
                    app.btnClose.Enable     = 'on';
                    app.btnErode.Enable     = 'on';
                    app.btnDilate.Enable    = 'on';
                    app.btnFill.Enable      = 'on';
                    app.btnSkel.Enable      = 'on';
                    app.btnMedian.Enable    = 'on';
                    app.btnUltierode.Enable = 'on';                    
                    app.btnIntersect.Enable = 'on';
                    app.btnUnite.Enable     = 'on';
                    app.btnExclude.Enable   = 'on';
                    app.btnSubtract.Enable  = 'on';
                    
                    app.btnSaveMask.Enable  = 'on';
                    app.btnMaskInfo.Enable  = 'on';
                    app.btnsVisualizeMaskSelectionChanged;
                otherwise
                    app.btnDrawMask1.Enable = 'off';
                    app.btnDrawMask2.Enable = 'off';
                    app.btnDrawMask3.Enable = 'off';
                    app.Zslider.Enable      = 'off';                    
                    app.btnRevert.Enable    = 'off';
                    app.btnContour.Enable   = 'off';
                    app.btnInvert.Enable    = 'off';
                    app.btnClose.Enable     = 'off';
                    app.btnErode.Enable     = 'off';
                    app.btnDilate.Enable    = 'off';
                    app.btnFill.Enable      = 'off';
                    app.btnSkel.Enable      = 'off';
                    app.btnMedian.Enable    = 'off';
                    app.btnUltierode.Enable = 'off';                    
                    app.btnIntersect.Enable = 'off';
                    app.btnUnite.Enable     = 'off';
                    app.btnExclude.Enable   = 'off';
                    app.btnSubtract.Enable  = 'off';
                    app.btnSaveMask.Enable  = 'off';
                    app.btnMaskInfo.Enable  = 'off';
            end
            
            close(dlg);
        end

        % Selection changed function: btnsVisualizeMask
        function btnsVisualizeMaskSelectionChanged(app, event)
            selectedButton = app.btnsVisualizeMask.SelectedObject;
            oldZvalue = ceil(app.Zslider.Value);
            
            switch selectedButton.Text
                case 'Result'
                    if strcmp(app.Zslider.Enable, 'on')
                        % 3D images
                        app.Zslider.Limits = [1 size(app.Mask3, 3)];
                        if  oldZvalue >= app.Zslider.Limits(1) && oldZvalue <= app.Zslider.Limits(2)
                            app.Zslider.Value = oldZvalue;
                        else
                            app.Zslider.Value = ceil(size(app.Mask3, 3)/2);
                        end                   
                        imagesc(app.UIAxesMask, app.Mask3(:,:,app.Zslider.Value));
                    else
                        % 2D images
                        imagesc(app.UIAxesMask, app.Mask3);
                    end
                    xlim(app.UIAxesMask, [1 size(app.Mask3,1)]);
                    ylim(app.UIAxesMask, [1 size(app.Mask3,2)]);                    
                case 'Mask I'
                    try
                        % 3D images, scroll along Z
                        app.Zslider.Enable = 'on';
                        app.Zslider.Limits = [1 size(app.Mask1, 3)];
                        if  oldZvalue >= app.Zslider.Limits(1) && oldZvalue <= app.Zslider.Limits(2)
                            app.Zslider.Value = oldZvalue;
                        else
                            app.Zslider.Value = ceil(size(app.Mask1, 3)/2);
                        end
                        imagesc(app.UIAxesMask, app.Mask1(:,:,app.Zslider.Value));
                    catch
                        % 2D images, no scrolling
                        app.Zslider.Enable = 'off';
                        imagesc(app.UIAxesMask, app.Mask1);
                    end
                    xlim(app.UIAxesMask, [1 size(app.Mask1,1)]);
                    ylim(app.UIAxesMask, [1 size(app.Mask1,2)]);
                    
                case 'Mask II'
                    try
                        % 3D images, scroll along Z
                        app.Zslider.Enable = 'on';
                        app.Zslider.Limits = [1 size(app.Mask2, 3)];
                        if  oldZvalue >= app.Zslider.Limits(1) && oldZvalue <= app.Zslider.Limits(2)
                            app.Zslider.Value = oldZvalue;
                        else
                            app.Zslider.Value = ceil(size(app.Mask2, 3)/2);
                        end
                        imagesc(app.UIAxesMask, app.Mask2(:,:,app.Zslider.Value));
                    catch
                        % 2D images, no scrolling
                        app.Zslider.Enable = 'off';
                        imagesc(app.UIAxesMask, app.Mask2);
                    end
                    xlim(app.UIAxesMask, [1 size(app.Mask2,1)]);
                    ylim(app.UIAxesMask, [1 size(app.Mask2,2)]);
            end

            colormap(app.UIAxesMask, gray(2));

        end

        % Value changed function: Zslider
        function ZsliderValueChanged(app, event)
            selectedButton = app.btnsVisualizeMask.SelectedObject;
            Zvalue = ceil(app.Zslider.Value);
            switch selectedButton.Text
                case 'Result', imagesc(app.UIAxesMask, app.Mask3(:,:,Zvalue));
                case 'Mask I', imagesc(app.UIAxesMask, app.Mask1(:,:,Zvalue));
                case 'Mask II',imagesc(app.UIAxesMask, app.Mask2(:,:,Zvalue));
            end              
        end

        % Button pushed function: btnRevert
        function btnRevertButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = app.Mask1;
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;

            close(dlg);
        end

        % Button pushed function: btnIntersect
        function btnIntersectButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = and(app.Mask1, app.Mask2);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;   
            
            close(dlg);
        end

        % Button pushed function: btnUnite
        function btnUniteButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = or(app.Mask1, app.Mask2);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged; 
            
            close(dlg);
        end

        % Button pushed function: btnExclude
        function btnExcludeButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = xor(app.Mask1, app.Mask2);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;  
            
            close(dlg);
        end

        % Button pushed function: btnInvert
        function btnIntersect_6ButtonPushed2(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');  
            
            app.Mask3 = not(app.Mask3);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;             

            close(dlg);
        end

        % Button pushed function: btnSubtract
        function btnSubtractButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = logical(uint8(app.Mask1)-uint8(app.Mask2));
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;                        

            close(dlg);
        end

        % Button pushed function: btnContour
        function btnIntersect_5ButtonPushed2(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = edge3(app.Mask3,'Sobel', 0.5, 'nothinning');
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;
            
            close(dlg);
        end

        % Button pushed function: btnSaveMask
        function btnSaveMaskButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Saving result mask...','Indeterminate', 'on');
            
            option.overwrite = true;            
            saveastiff(uint8(app.Mask3), fullfile(app.Ipath, 'ResultMask.tif'), option);
            
            close(dlg);
        end

        % Window scroll wheel function: VolumeCutUIFigure
        function VolumeCutUIFigureWindowScrollWheel(app, event)
            %verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;            
            %disp(['verticalScrollAmount = ' num2str(verticalScrollAmount)]);
            %disp(['verticalScrollCount = ' num2str(verticalScrollCount)]);

            activeTab = app.TabGroup.SelectedTab;
            if strcmp(activeTab.Title, 'Mask') && strcmp(app.Zslider.Enable,'on')
                % Scroll the current Mask stack along Z
                if verticalScrollCount > 0
                    app.Zslider.Value = min(max(app.Zslider.Limits), ceil(app.Zslider.Value +1));
                else
                    app.Zslider.Value = max(min(app.Zslider.Limits), ceil(app.Zslider.Value -1));
                end
                app.ZsliderValueChanged;
            end
        end

        % Value changing function: Zslider
        function ZsliderValueChanging(app, event)
            selectedButton = app.btnsVisualizeMask.SelectedObject;
            Zvalue = ceil(event.Value);

            switch selectedButton.Text
                case 'Result', imagesc(app.UIAxesMask, app.Mask3(:,:,Zvalue));
                case 'Mask I', imagesc(app.UIAxesMask, app.Mask1(:,:,Zvalue));
                case 'Mask II',imagesc(app.UIAxesMask, app.Mask2(:,:,Zvalue));
            end                         
        end

        % Button pushed function: btnMaskInfo
        function btnMaskInfoButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Calculating mask info...','Indeterminate', 'on');
            if ~isempty(app.Mask1)
                vol1        = numel(find(app.Mask1));
                info        = {'\bf\fontsize{10}Mask 1 statistics:\rm'};
                info{end+1} = ['Total voxels :' num2str(numel(app.Mask1))];
                info{end+1} = ['Masked voxels :' num2str(vol1) ' (' num2str(100*vol1/numel(app.Mask1)) '%)'];
                info{end+1} = ' ';
            end
            
            if ~isempty(app.Mask2)
                vol2        = numel(find(app.Mask2));
                info{end+1} = '\bf\fontsize{10}Mask 2 statistics:\rm';
                info{end+1} = ['Total volume (voxels) :' num2str(numel(app.Mask2))];
                info{end+1} = ['Masked voxels :' num2str(vol2) ' (' num2str(100*vol2/numel(app.Mask2)) '%)'];
                info{end+1} = ' ';
                
                overlap     = numel(find(app.Mask1 & app.Mask2));
                info{end+1} = '\bf\fontsize{10}Overlap statistics:\rm';
                info{end+1} = ['Voxels: ' num2str(overlap)];
                info{end+1} = ['Volume: ' num2str(100*overlap/vol1) '% of Mask 1'];
                info{end+1} = ['Volume: ' num2str(100*overlap/vol2) '% of Mask 2'];
            end
            close(dlg);
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            msgbox(info, 'Masks info', CreateStruct);
        end

        % Button pushed function: btnClose
        function btnCloseButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            % 3D version - fails to close if last or first z plane is open
            %SE = strel('sphere',1); % Structure element is a sphere with radius 1 pixel                        
            %app.Mask3 = imclose(app.Mask3, SE);
            
            SE = strel('disk',1); % Structure element is a disk with radius 1 pixel                        
            for i=1:size(app.Mask3,3)
                app.Mask3(:,:,i) = imclose(app.Mask3(:,:,i),SE);
            end            
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;
            
            close(dlg);
        end

        % Button pushed function: btnErode
        function btnErodeButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            SE = strel('sphere',1); % Structure element is a sphere with radius 1 pixel
            app.Mask3 = imerode(app.Mask3, SE);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;
            
            close(dlg);
        end

        % Button pushed function: btnDilate
        function btnDilateButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            SE = strel('sphere',1); % Structure element is a sphere with radius 1 pixel            
            app.Mask3 = imdilate(app.Mask3, SE);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;

            close(dlg);
        end

        % Button pushed function: btnFill
        function btnFillButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            %app.Mask3 = imfill(app.Mask3, 'holes'); % 3D version
            for i=1:size(app.Mask3,3)
                app.Mask3(:,:,i) = imfill(app.Mask3(:,:,i),'holes');
            end
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;
            
            close(dlg);
        end

        % Button pushed function: btnSkel
        function btnSkelButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = bwskel(app.Mask3);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;
            
            close(dlg);
        end

        % Button pushed function: btnMedian
        function btnMedianButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = medfilt3(app.Mask3);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;
            
            close(dlg)
        end

        % Button pushed function: btnUltierode
        function btnUltierodeButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing mask...','Indeterminate', 'on');                                

            app.Mask3 = bwulterode(app.Mask3);
            app.btnDrawMask3.Enable = 'on';
            app.btnDrawMask3.Value = true;
            app.btnsVisualizeMaskSelectionChanged;
            
            close(dlg);
        end

        % Button pushed function: btnLoadSkel
        function btnLoadSkelButtonPushed(app, event)
            [FileName, PathName] = uigetfile('*.traces', 'Select skeleton file', 'MultiSelect', 'off');
            app.VolumeCutUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.VolumeCutUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus

            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Loading skeleton...','Indeterminate', 'on');                                
            
            try
                cd(PathName);
                app.SkelPath = PathName;
                app.Skel = snt2ml(PathName, FileName, false);
                app.SkelResult = app.Skel;

                app.btnSkelSave.Enable      = 'on';
                app.btnSkelReset.Enable     = 'on';
                app.btnSkelInfo.Enable      = 'on';
                app.btnSkel2Mask.Enable     = 'on';
                app.btnSkelReflectX.Enable  = 'on';
                app.btnSkelReflectY.Enable  = 'on';
                app.btnSkelReflectZ.Enable  = 'on';
                app.btnSkelRecalibrate.Enable = 'on';
                app.btnSkelResize.Enable    = 'on';
            catch
                app.Skel = [];
                
                app.btnSkelSave.Enable      = 'off';
                app.btnSkelReset.Enable     = 'off';
                app.btnSkelInfo.Enable      = 'off';
                app.btnSkel2Mask.Enable     = 'off';
                app.btnSkelReflectX.Enable  = 'off';
                app.btnSkelReflectY.Enable  = 'off';
                app.btnSkelReflectZ.Enable  = 'off';
                app.btnSkelRecalibrate.Enable = 'on';
                app.btnSkelResize.Enable    = 'on';
            end
            app.plotSkeleton;
            close(dlg);
        end

        % Button pushed function: btnSkel2Mask
        function btnSkel2MaskButtonPushed(app, event)
            ExtraRadius.XY = 0;
            ExtraRadius.Z  = 0;
            settings = editStruct(ExtraRadius);
            if isempty(settings)
                return
            end
            
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Generating mask...','Indeterminate', 'on');                                

            app.Mask1       = logical(skel2mask(app.SkelResult, settings.XY, settings.Z));
            app.Mask3       = app.Mask1;

            close(dlg);
            
            app.btnDrawMask1.Enable = 'on';
            app.btnDrawMask1.Value  = true;
            app.btnDrawMask2.Enable = 'off';
            app.btnDrawMask3.Enable = 'on';
            
            app.btnRevert.Enable    = 'on';
            app.btnContour.Enable   = 'on';
            app.btnInvert.Enable    = 'on';
            app.btnClose.Enable     = 'on';
            app.btnErode.Enable     = 'on';
            app.btnDilate.Enable    = 'on';
            app.btnFill.Enable      = 'on';
            app.btnSkel.Enable      = 'on';
            app.btnMedian.Enable    = 'on';
            app.btnUltierode.Enable = 'on';
            app.btnIntersect.Enable = 'off';
            app.btnUnite.Enable     = 'off';
            app.btnExclude.Enable   = 'off';
            app.btnSubtract.Enable  = 'off';
            
            app.btnSaveMask.Enable  = 'on';
            app.btnMaskInfo.Enable  = 'on';
            
            app.btnsVisualizeMaskSelectionChanged;
            app.TabGroup.SelectedTab = app.MaskTab;                
        end

        % Button pushed function: btnSkelSave
        function btnSkelSaveButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Saving skeleton...','Indeterminate', 'on');                                
            
            skel = app.SkelResult;
            save(fullfile(app.SkelPath, 'ResultSkeleton.mat'), 'skel');
            
            close(dlg);
        end

        % Button pushed function: btnSkelReset
        function btnSkelResetButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Plotting skeleton...','Indeterminate', 'on');                                
            app.SkelResult = app.Skel;
            app.plotSkeleton;
            close(dlg);
        end

        % Button pushed function: btnSkelReflectX
        function btnSkelReflectXButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing skeleton...','Indeterminate', 'on');                                

            for i = 1 : numel(app.SkelResult.branches)
                app.SkelResult.branches(i).points(:,2) = app.SkelResult.imgsize.height - app.SkelResult.branches(i).points(:,2);
                app.SkelResult.branches(i).points(:,5) = app.SkelResult.imgsize.height*app.SkelResult.calib.y - app.SkelResult.branches(i).points(:,5);
            end
            app.plotSkeleton;
            close(dlg);
        end

        % Button pushed function: btnSkelReflectY
        function btnSkelReflectYButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing skeleton...','Indeterminate', 'on'); 

            for i = 1 : numel(app.SkelResult.branches)
                app.SkelResult.branches(i).points(:,1) = app.SkelResult.imgsize.width - app.SkelResult.branches(i).points(:,1);
                app.SkelResult.branches(i).points(:,4) = app.SkelResult.imgsize.width*app.SkelResult.calib.x - app.SkelResult.branches(i).points(:,4);
            end
            app.plotSkeleton;
            close(dlg);
        end

        % Button pushed function: btnSkelReflectZ
        function btnSkelReflectZButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing skeleton...','Indeterminate', 'on'); 
            for i = 1 : numel(app.SkelResult.branches)
                app.SkelResult.branches(i).points(:,3) = app.SkelResult.imgsize.depth - app.SkelResult.branches(i).points(:,3);
                app.SkelResult.branches(i).points(:,6) = app.SkelResult.imgsize.depth*app.SkelResult.calib.z - app.SkelResult.branches(i).points(:,6);
            end
            app.plotSkeleton;   
            close(dlg);
        end

        % Button pushed function: btnSkelInfo
        function btnSkelInfoButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Calculating skeleton info...','Indeterminate', 'on');
            if ~isempty(app.SkelResult)
                info        = {'\bf\fontsize{10}Skeleton statistics:\rm'};
                info{end+1} = ['Total length : ' num2str(sum([app.SkelResult.branches.length])) ' microns'];
                info{end+1} = ['Number of points : ' num2str(size(cat(1, app.SkelResult.branches.points),1))];
                info{end+1} = ['Number of segments : ' num2str(numel(app.SkelResult.branches))];
                info{end+1} = ['Maximum order : ' num2str(app.SkelResult.maxOrder)];
                info{end+1} = ['Image size : ' num2str(app.SkelResult.imgsize.width) 'x' num2str(app.SkelResult.imgsize.height) 'x' num2str(app.SkelResult.imgsize.depth) ' pixels'];
                info{end+1} = ['Voxel size : ' num2str(app.SkelResult.calib.x) 'x' num2str(app.SkelResult.calib.y) 'x' num2str(app.SkelResult.calib.z) ' microns'];
            end
            close(dlg);
            CreateStruct.Interpreter = 'tex';
            CreateStruct.WindowStyle = 'modal';
            msgbox(info, 'Skeleton info', CreateStruct);            
        end

        % Button pushed function: btnSkelRecalibrate
        function btnSkelRecalibrateButtonPushed(app, event)
            % Retrieve current skeleton's resolution
            settings.x = app.SkelResult.calib.x;
            settings.y = app.SkelResult.calib.y;
            settings.z = app.SkelResult.calib.z;
            
            % Ask user to input the new resolution
            newSettings = editStruct(settings);
            if isempty(newSettings)
                return
            end

            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing skeleton...','Indeterminate', 'on');
            
            % Calculate the rescaling factor for each dimension
            zoom.x = newSettings.x / app.SkelResult.calib.x;
            zoom.y = newSettings.y / app.SkelResult.calib.y;
            zoom.z = newSettings.z / app.SkelResult.calib.z;
            
            % Recalibrate real-world distances (keep pixel positions the same)
            for i = 1:numel(app.SkelResult.branches)
                app.SkelResult.branches(i).points(:,4) = zoom.x * app.SkelResult.branches(i).points(:,4); % Calibrated X
                app.SkelResult.branches(i).points(:,5) = zoom.y * app.SkelResult.branches(i).points(:,5); % Calibrated Y
                app.SkelResult.branches(i).points(:,6) = zoom.z * app.SkelResult.branches(i).points(:,6); % Calibrated Z
                app.SkelResult.branches(i).points(:,7) = zoom.x * app.SkelResult.branches(i).points(:,7); % Radius
            end
            
            % Recalculate length of branches
            for i = 1:numel(app.SkelResult.branches)
               d = 0;
               for p = 2:size(app.SkelResult.branches(i).points,1)
                   % Progressively add distance between consecutive pair of points
                   d = d + pdist(app.SkelResult.branches(i).points(p-1:p, 4:6));
               end
               app.SkelResult.branches(i).length = d;
            end
            app.SkelResult.totalLength = sum([app.SkelResult.branches.length]);

            % Update calibration info and replot
            app.SkelResult.calib.x = newSettings.x;
            app.SkelResult.calib.y = newSettings.y;
            app.SkelResult.calib.z = newSettings.z;
            
            app.plotSkeleton;
            close(dlg);
        end

        % Button pushed function: btnSkelResize
        function btnSkelResizeButtonPushed(app, event)
            % Retrieve current skeleton's resolution
            zoom.x = 1;
            zoom.y = 1;
            zoom.z = 1;
            
            % Ask user to input the new resolution
            zoom = editStruct(zoom);
            if isempty(zoom)
                return
            end
            
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Processing skeleton...','Indeterminate', 'on');

            % Adjust image size
            app.SkelResult.imgsize.width  = zoom.x * app.SkelResult.imgsize.width;
            app.SkelResult.imgsize.height = zoom.y * app.SkelResult.imgsize.height;
            app.SkelResult.imgsize.depth  = zoom.z * app.SkelResult.imgsize.depth;
            
            % Recalibrate real-world distances (keep pixel positions the same)
            for i=1:numel(app.SkelResult.branches)
                app.SkelResult.branches(i).points(:,1) = zoom.x * app.SkelResult.branches(i).points(:,1); % X
                app.SkelResult.branches(i).points(:,2) = zoom.y * app.SkelResult.branches(i).points(:,2); % Y
                app.SkelResult.branches(i).points(:,3) = zoom.z * app.SkelResult.branches(i).points(:,3); % Z
                app.SkelResult.branches(i).points(:,4) = zoom.x * app.SkelResult.branches(i).points(:,4); % Calibrated X
                app.SkelResult.branches(i).points(:,5) = zoom.y * app.SkelResult.branches(i).points(:,5); % Calibrated Y
                app.SkelResult.branches(i).points(:,6) = zoom.z * app.SkelResult.branches(i).points(:,6); % Calibrated Z
                app.SkelResult.branches(i).points(:,7) = zoom.x * app.SkelResult.branches(i).points(:,7); % Radius
            end
            
            % Recalculate length of branches
            for i = 1:numel(app.SkelResult.branches)
               d = 0;
               for p = 2:size(app.SkelResult.branches(i).points,1)
                   % Progressively add distance between consecutive pair of points
                   d = d + pdist(app.SkelResult.branches(i).points(p-1:p, 4:6));
               end
               app.SkelResult.branches(i).length = d;
            end
            app.SkelResult.totalLength = sum([app.SkelResult.branches.length]);           
            app.plotSkeleton;
            close(dlg);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.CutTab
                app.VolumeCutUIFigure.WindowScrollWheelFcn = '';
            elseif selectedTab == app.MaskTab
                app.VolumeCutUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @VolumeCutUIFigureWindowScrollWheel, true);
            end            
        end

        % Button pushed function: btnBatchFolder
        function btnBatchFolderButtonPushed(app, event)
            PathName = uigetdir(pwd , 'Select root folder');
            if ~isempty(PathName)
                cd(PathName);
            end
            
            app.VolumeCutUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.VolumeCutUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus            
        end

        % Button pushed function: btnBatchProcess
        function btnBatchProcessButtonPushed(app, event)
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Batch processing...','Value', 0, 'Cancelable', 'on'); 

            rootFolder = pwd;            
            TableData = [];
            
            if app.chkBatchSubfolders.Value
                AllDirs = dir('**/*.*');
                dirs = AllDirs([AllDirs.isdir] & strcmp({AllDirs.name},'.'));
            else
                dirs = dir;
                dirs = dirs([dirs.isdir]);            
                dirs = dirs(1);
            end
            
            for d = 1:numel(dirs)
                dlg.Title = ['Processing folder ' num2str(d) '/' num2str(numel(dirs)) ' : ' dirs(d).name];
                
                cd([dirs(d).folder filesep dirs(d).name]); 
                files = dir(app.txtBatchMaskFilesPattern.Value);

                switch app.cmbBatchOperation.Value
                    case 'Measure intensity'
                        % Discover raw images matching user specified pattern
                        filesRaw = dir(app.txtBatchRawFilesPattern.Value);
                        if numel(filesRaw) == 0
                            continue
                        end
                        
                        % Load all raw files in the folder
                        Iraw = struct;
                        Iraw(numel(filesRaw)).Name = '';
                        Iraw(numel(filesRaw)).I = [];
                        for r = 1:numel(filesRaw)
                            Iraw(r).Name = filesRaw(r).name;
                            dlg.Message = ['Loading raw signal: ' filesRaw(r).name];
                            disp(['Loading raw channel: ' Iraw(r).Name]);
                            Iraw(r).I = uint8(loadImageStack([pwd filesep], Iraw(r).Name));
                        end
                end
                
                for f = 1:numel(files)
                    if files(f).isdir
                        continue; % Do not process directories
                    else
                        disp(['Measuring mask: ' files(f).name]);
                        dlg.Message = ['Measuring mask ' num2str(f) '/' num2str(numel(files)) ' : ' files(f).name];
                        dlg.Value = f/numel(files);
                        I = loadImageStack([pwd filesep], files(f).name);
                        I = logical(I);
                    end
                    
                    switch app.cmbBatchOperation.Value
                        case 'Measure size'
                            ImageName   = files(f).name;
                            ImageVox    = numel(I);
                            MaskedVox   = numel(find(I));
                            MaskedPerc  = 100*MaskedVox/ImageVox;                            
                            RowData = table({dirs(d).folder}, {ImageName}, ImageVox, MaskedVox, MaskedPerc, 'VariableNames', {'Folder', 'Image', 'TotalVoxels', 'MaskedVoxels', 'MaskedPerc'});
                            if isempty(TableData)
                                TableData = RowData;
                            else
                                TableData = [TableData; RowData]; %#ok
                            end
                            
                        case 'Measure intensity'
                            for r = 1:numel(Iraw)
                                MaskName   = files(f).name;
                                MaskedRaw   = Iraw(r).I(I);
                                MaskedHist  = histcounts(MaskedRaw, 0:256);
                                RowData = table({dirs(d).folder}, {Iraw(r).Name}, {MaskName}, MaskedHist, 'VariableNames', {'Folder', 'RawImage', 'Mask', 'Bin'});
                                if isempty(TableData)
                                    TableData = RowData;
                                else
                                    TableData = [TableData; RowData]; %#ok
                                end
                            end
                        case 'Density by depth'
                            Density = app.calcDensityByDepth(I);
                            RowData = table({dirs(d).folder}, {files(f).name}, Density.densityPerc, 'VariableNames', {'Folder', 'Image', 'DepthPercent'});                            
                            if isempty(TableData)
                                TableData = RowData;
                            else
                                TableData = [TableData; RowData]; %#ok
                            end
                        case 'Keep largest object'
                            dlg.Message = {['Mask ' num2str(f) '/' num2str(numel(files)) ' : ' files(f).name], 'Identifying unique objects...'};
                            RP = regionprops3(I, 'Volume', 'VoxelIdxList');
                            
                            dlg.Message = {['Mask ' num2str(f) '/' num2str(numel(files)) ' : ' files(f).name], 'Keeping largest object...'};
                            V = sort([RP.Volume]);
                            Iout = false(size(I));
                            Iout(RP.VoxelIdxList{RP.Volume == V(end)}) = true;
                            
                            dlg.Message = {['Mask ' num2str(f) '/' num2str(numel(files)) ' : ' files(f).name], 'Saving result mask...'};
                            [~, fName, ~] = fileparts(files(f).name);
                            option.overwrite = true;            
                            saveastiff(uint8(Iout), [dirs(d).name filesep fName '_largestObj.tif'], option);                            
                    end                    
                    
                    % Interrupt batch processing at this point if user pressed the cancel button
                    if dlg.CancelRequested
                        break
                    end
                end
            end
            
            % Write results table to disk as an Excel spreadsheet
            if ~isempty(TableData)                                
                writetable(TableData, [rootFolder filesep 'Batch ' app.cmbBatchOperation.Value '.csv']);
            end
            
            cd(rootFolder);
            close(dlg);
        end

        % Value changed function: cmbBatchOperation
        function cmbBatchOperationValueChanged(app, event)
            switch app.cmbBatchOperation.Value
                case 'Measure intensity'
                    app.txtBatchRawFilesPattern.Enable = 'on';
                otherwise
                    app.txtBatchRawFilesPattern.Enable = 'off';
            end
            
        end

        % Button pushed function: btnSkelBinary
        function btnSkelBinaryButtonPushed(app, event)
            [FileName, PathName] = uigetfile('*.tif', 'Select binary skeleton to process', 'MultiSelect', 'off');
            app.VolumeCutUIFigure.Visible = 'off'; %Fixes glitch by which app loses focus
            app.VolumeCutUIFigure.Visible = 'on';  %Fixes glitch by which app loses focus
            if FileName == 0
                return
            end
            
            dlg = uiprogressdlg(app.VolumeCutUIFigure,'Title','Skeletonizing...','Indeterminate', 'on');                                
            
            M = loadImageStack(PathName, FileName);
            [A, node, link] = Skel2Graph3D(M,0);
            
            close(dlg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VolumeCutUIFigure = uifigure('Visible', 'off');
            app.VolumeCutUIFigure.Name = 'VolumeCut';
            app.VolumeCutUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @VolumeCutUIFigureWindowScrollWheel, true);
            app.TabGroup = uitabgroup(app.VolumeCutUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CutTab = uitab(app.TabGroup);
            app.CutTab.Title = 'Cut';
            app.btnCutVolume = uibutton(app.CutTab, 'push');
            app.btnCutVolume.ButtonPushedFcn = createCallbackFcn(app, @btnCutVolumeButtonPushed, true);
            app.btnCutVolume.Icon = '48px-Edit-cut.png';
            app.btnCutVolume.Enable = 'off';
            app.btnCutVolume.Tooltip = {'Cut the volume in selected mode'};
            app.btnCutVolume.Text = 'Cut Volume';
            app.btnLoadImage = uibutton(app.CutTab, 'push');
            app.btnLoadImage.ButtonPushedFcn = createCallbackFcn(app, @btnLoadImageButtonPushed, true);
            app.btnLoadImage.Icon = '48px-Image_upload.png';
            app.btnLoadImage.Tooltip = {'Select the image TIF file to cut'};
            app.btnLoadImage.Text = 'Load Image';
            app.btnSaveImage = uibutton(app.CutTab, 'push');
            app.btnSaveImage.ButtonPushedFcn = createCallbackFcn(app, @btnSaveImageButtonPushed, true);
            app.btnSaveImage.Icon = '48px-save-image.png';
            app.btnSaveImage.Enable = 'off';
            app.btnSaveImage.Tooltip = {'Save cut parts to file'};
            app.btnSaveImage.Text = 'Save Image';
            app.btnRefPoints = uibutton(app.CutTab, 'push');
            app.btnRefPoints.ButtonPushedFcn = createCallbackFcn(app, @btnRefPointsButtonPushed, true);
            app.btnRefPoints.Icon = '48px-Mypaint-icon.png';
            app.btnRefPoints.Enable = 'off';
            app.btnRefPoints.Tooltip = {'Load files with XYZ cut coordinates'};
            app.btnRefPoints.Text = 'Set Cut Points';
            app.pnlCuttersettings = uipanel(app.CutTab);
            app.pnlCuttersettings.Title = 'Surface settings';
            app.Offset1Label = uilabel(app.pnlCuttersettings);
            app.Offset1Label.Text = 'Offset #1';
            app.offset1 = uispinner(app.pnlCuttersettings);
            app.offset1.RoundFractionalValues = 'on';
            app.offset1.Tooltip = {'Positive values means deeper in the volume'};
            app.Offset2Label = uilabel(app.pnlCuttersettings);
            app.Offset2Label.Text = 'Offset #2';
            app.offset2 = uispinner(app.pnlCuttersettings);
            app.offset2.RoundFractionalValues = 'on';
            app.offset2.Tooltip = {'Positive values means deeper in the volume'};
            app.lblSmoothing = uilabel(app.pnlCuttersettings);
            app.lblSmoothing.Text = 'Smoothing';
            app.smoothing = uispinner(app.pnlCuttersettings);
            app.smoothing.Limits = [0 Inf];
            app.smoothing.RoundFractionalValues = 'on';
            app.smoothing.Value = 75;
            app.Offset3Label = uilabel(app.pnlCuttersettings);
            app.Offset3Label.Text = 'Offset #3';
            app.offset3 = uispinner(app.pnlCuttersettings);
            app.offset3.RoundFractionalValues = 'on';
            app.offset3.Tooltip = {'Positive values means deeper in the volume'};
            app.btnsCuttingMode = uibuttongroup(app.CutTab);
            app.btnsCuttingMode.SelectionChangedFcn = createCallbackFcn(app, @btnsCuttingModeSelectionChanged, true);
            app.btnsCuttingMode.Title = 'Cutting mode';
            app.btnCutModeThreeParts = uitogglebutton(app.btnsCuttingMode);
            app.btnCutModeThreeParts.Tooltip = {'Split in three parts'};
            app.btnCutModeThreeParts.Icon = '48px-CutMode1.png';
            app.btnCutModeThreeParts.Text = '';
            app.btnCutModeThreeParts.Value = true;
            app.btnCutModeFourParts = uitogglebutton(app.btnsCuttingMode);
            app.btnCutModeFourParts.Tooltip = {'Split in four parts'};
            app.btnCutModeFourParts.Icon = '48px-CutMode2.png';
            app.btnCutModeFourParts.Text = '';
            app.btnCutModeSurfMidSurf = uitogglebutton(app.btnsCuttingMode);
            app.btnCutModeSurfMidSurf.Tooltip = {'Split in two parts between surfaces'};
            app.btnCutModeSurfMidSurf.Icon = '48px-CutMode3.png';
            app.btnCutModeSurfMidSurf.Text = '';
            app.btnCutModeTwoParts = uitogglebutton(app.btnsCuttingMode);
            app.btnCutModeTwoParts.Tooltip = {'Split in two parts'};
            app.btnCutModeTwoParts.Icon = '48px-CutMode0.png';
            app.btnCutModeTwoParts.Text = '';
            app.btnFitSurface = uibutton(app.CutTab, 'push');
            app.btnFitSurface.ButtonPushedFcn = createCallbackFcn(app, @btnFitSurfaceButtonPushed, true);
            app.btnFitSurface.Icon = '48px-surface.png';
            app.btnFitSurface.Enable = 'off';
            app.btnFitSurface.Tooltip = {'Fit surfaces across cut points'};
            app.btnFitSurface.Text = 'Fit Surfaces';
            app.RenderingPanel = uipanel(app.CutTab);
            app.RenderingPanel.Title = 'Rendering';
            app.btnPreview = uibutton(app.RenderingPanel, 'push');
            app.btnPreview.ButtonPushedFcn = createCallbackFcn(app, @btnPreviewPushed, true);
            app.btnPreview.Icon = '48px-preview-photos.png';
            app.btnPreview.Tooltip = {'Render the selected volume in 3D'};
            app.btnPreview.Text = {'Render'; 'Volume'};
            app.volumeToRender = uidropdown(app.RenderingPanel);
            app.volumeToRender.Items = {'Raw Image', 'Cut I', 'Cut II', 'Cut III', 'Cut IV'};
            app.volumeToRender.Tooltip = {'Choose which volume to render'};
            app.volumeToRender.Value = 'Raw Image';
            app.btnRedraw = uibutton(app.CutTab, 'push');
            app.btnRedraw.ButtonPushedFcn = createCallbackFcn(app, @btnRedrawButtonPushed, true);
            app.btnRedraw.Icon = '48px-refresh.png';
            app.btnRedraw.Tooltip = {'Refresh plot'};
            app.btnRedraw.Text = '';
            app.lblIname = uilabel(app.CutTab);
            app.lblIname.Text = 'Image file:';
            app.lblPts1name = uilabel(app.CutTab);
            app.lblPts1name.Text = 'Cut Points #1:';
            app.lblPts2name = uilabel(app.CutTab);
            app.lblPts2name.Text = 'Cut Points #2:';
            app.lblPts3name = uilabel(app.CutTab);
            app.lblPts3name.Text = 'Cut Points #3:';
            app.cmbCalibrated = uidropdown(app.CutTab);
            app.cmbCalibrated.Items = {'Px', 'um'};
            app.cmbCalibrated.ValueChangedFcn = createCallbackFcn(app, @cmbCalibratedValueChanged, true);
            app.cmbCalibrated.Tooltip = {'Measurement unit'};
            app.cmbCalibrated.Value = 'Px';
            app.UIAxes = uiaxes(app.CutTab);
            app.MaskTab = uitab(app.TabGroup);
            app.MaskTab.Title = 'Mask';
            app.btnLoadMask = uibutton(app.MaskTab, 'push');
            app.btnLoadMask.ButtonPushedFcn = createCallbackFcn(app, @btnLoadMaskButtonPushed, true);
            app.btnLoadMask.Icon = '48px-open-mask.png';
            app.btnLoadMask.Tooltip = {'Load one or two binary 3D TIF masks to process'};
            app.btnLoadMask.Text = 'Load Masks';
            app.btnSaveMask = uibutton(app.MaskTab, 'push');
            app.btnSaveMask.ButtonPushedFcn = createCallbackFcn(app, @btnSaveMaskButtonPushed, true);
            app.btnSaveMask.Icon = '48px-save-mask.png';
            app.btnSaveMask.Enable = 'off';
            app.btnSaveMask.Tooltip = {'Save Result mask to file'};
            app.btnSaveMask.Text = 'Save Result';
            app.BetweenMaskIIIPanel = uipanel(app.MaskTab);
            app.BetweenMaskIIIPanel.Title = 'Between Mask I & II';
            app.btnIntersect = uibutton(app.BetweenMaskIIIPanel, 'push');
            app.btnIntersect.ButtonPushedFcn = createCallbackFcn(app, @btnIntersectButtonPushed, true);
            app.btnIntersect.Icon = '48px-logical-intersection.png';
            app.btnIntersect.Enable = 'off';
            app.btnIntersect.Text = 'Intersect';
            app.btnUnite = uibutton(app.BetweenMaskIIIPanel, 'push');
            app.btnUnite.ButtonPushedFcn = createCallbackFcn(app, @btnUniteButtonPushed, true);
            app.btnUnite.Icon = '48px-logical-unite.png';
            app.btnUnite.Enable = 'off';
            app.btnUnite.Text = 'Unite';
            app.btnSubtract = uibutton(app.BetweenMaskIIIPanel, 'push');
            app.btnSubtract.ButtonPushedFcn = createCallbackFcn(app, @btnSubtractButtonPushed, true);
            app.btnSubtract.Icon = '48pxlogical-difference.png';
            app.btnSubtract.Enable = 'off';
            app.btnSubtract.Text = 'Subctract';
            app.btnExclude = uibutton(app.BetweenMaskIIIPanel, 'push');
            app.btnExclude.ButtonPushedFcn = createCallbackFcn(app, @btnExcludeButtonPushed, true);
            app.btnExclude.Icon = '48px-logical-exclusion.png';
            app.btnExclude.Enable = 'off';
            app.btnExclude.Text = 'Exclude';
            app.btnsVisualizeMask = uibuttongroup(app.MaskTab);
            app.btnsVisualizeMask.SelectionChangedFcn = createCallbackFcn(app, @btnsVisualizeMaskSelectionChanged, true);
            app.btnsVisualizeMask.Title = 'Visualize Mask';
            app.btnDrawMask3 = uitogglebutton(app.btnsVisualizeMask);
            app.btnDrawMask3.Enable = 'off';
            app.btnDrawMask3.Text = 'Result';
            app.btnDrawMask3.Value = true;
            app.btnDrawMask1 = uitogglebutton(app.btnsVisualizeMask);
            app.btnDrawMask1.Enable = 'off';
            app.btnDrawMask1.Text = 'Mask I';
            app.btnDrawMask2 = uitogglebutton(app.btnsVisualizeMask);
            app.btnDrawMask2.Enable = 'off';
            app.btnDrawMask2.Text = 'Mask II';
            app.Zslider = uislider(app.MaskTab);
            app.Zslider.ValueChangedFcn = createCallbackFcn(app, @ZsliderValueChanged, true);
            app.Zslider.ValueChangingFcn = createCallbackFcn(app, @ZsliderValueChanging, true);
            app.Zslider.MinorTicks = [];
            app.Zslider.Enable = 'off';
            app.Zslider.Tooltip = {'Z position'};
            app.ResultmaskoperationsPanel = uipanel(app.MaskTab);
            app.ResultmaskoperationsPanel.Title = 'Result mask operations';
            app.btnRevert = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnRevert.ButtonPushedFcn = createCallbackFcn(app, @btnRevertButtonPushed, true);
            app.btnRevert.Icon = '48px-logical-operations.png';
            app.btnRevert.Enable = 'off';
            app.btnRevert.Text = 'Reset';
            app.btnContour = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnContour.ButtonPushedFcn = createCallbackFcn(app, @btnIntersect_5ButtonPushed2, true);
            app.btnContour.Icon = '48px-logical-contour.png';
            app.btnContour.Enable = 'off';
            app.btnContour.Text = 'Contour';
            app.btnInvert = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnInvert.ButtonPushedFcn = createCallbackFcn(app, @btnIntersect_6ButtonPushed2, true);
            app.btnInvert.Icon = '48px-logical-invert.png';
            app.btnInvert.Enable = 'off';
            app.btnInvert.Text = 'Invert';
            app.btnClose = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnClose.ButtonPushedFcn = createCallbackFcn(app, @btnCloseButtonPushed, true);
            app.btnClose.Icon = '48px-logical-close.png';
            app.btnClose.Enable = 'off';
            app.btnClose.Text = 'Close';
            app.btnErode = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnErode.ButtonPushedFcn = createCallbackFcn(app, @btnErodeButtonPushed, true);
            app.btnErode.Icon = '48px-logical-erode.png';
            app.btnErode.Enable = 'off';
            app.btnErode.Text = 'Erode';
            app.btnDilate = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnDilate.ButtonPushedFcn = createCallbackFcn(app, @btnDilateButtonPushed, true);
            app.btnDilate.Icon = '48px-logical-dilate.png';
            app.btnDilate.Enable = 'off';
            app.btnDilate.Text = 'Dilate';
            app.btnFill = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnFill.ButtonPushedFcn = createCallbackFcn(app, @btnFillButtonPushed, true);
            app.btnFill.Icon = '48px-logical-fill.png';
            app.btnFill.Enable = 'off';
            app.btnFill.Text = 'Fill holes';
            app.btnSkel = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnSkel.ButtonPushedFcn = createCallbackFcn(app, @btnSkelButtonPushed, true);
            app.btnSkel.Icon = '48px-logical-skeleton.png';
            app.btnSkel.Enable = 'off';
            app.btnSkel.Text = 'Skeleton';
            app.btnUltierode = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnUltierode.ButtonPushedFcn = createCallbackFcn(app, @btnUltierodeButtonPushed, true);
            app.btnUltierode.Icon = '48px-logical-Ultierode.png';
            app.btnUltierode.Enable = 'off';
            app.btnUltierode.Text = 'Ultierode';
            app.btnMedian = uibutton(app.ResultmaskoperationsPanel, 'push');
            app.btnMedian.ButtonPushedFcn = createCallbackFcn(app, @btnMedianButtonPushed, true);
            app.btnMedian.Icon = '48px-logical-median.png';
            app.btnMedian.Enable = 'off';
            app.btnMedian.Tooltip = {'Median filter the mask'};
            app.btnMedian.Text = 'Median';
            app.btnMaskInfo = uibutton(app.MaskTab, 'push');
            app.btnMaskInfo.ButtonPushedFcn = createCallbackFcn(app, @btnMaskInfoButtonPushed, true);
            app.btnMaskInfo.Icon = '48px-info.png';
            app.btnMaskInfo.Enable = 'off';
            app.btnMaskInfo.Tooltip = {'Show statistics of current masks'};
            app.btnMaskInfo.Text = 'Info';
            app.UIAxesMask = uiaxes(app.MaskTab);
            app.UIAxesMask.XTick = [];
            app.UIAxesMask.YTick = [];
            app.SkeletonTab = uitab(app.TabGroup);
            app.SkeletonTab.Title = 'Skeleton';
            app.btnLoadSkel = uibutton(app.SkeletonTab, 'push');
            app.btnLoadSkel.ButtonPushedFcn = createCallbackFcn(app, @btnLoadSkelButtonPushed, true);
            app.btnLoadSkel.Icon = '48px-open-skel.png';
            app.btnLoadSkel.Tooltip = {'Load one or two binary 3D TIF masks to process'};
            app.btnLoadSkel.Text = 'Load';
            app.btnSkel2Mask = uibutton(app.SkeletonTab, 'push');
            app.btnSkel2Mask.ButtonPushedFcn = createCallbackFcn(app, @btnSkel2MaskButtonPushed, true);
            app.btnSkel2Mask.Icon = '48px-Masks.png';
            app.btnSkel2Mask.Enable = 'off';
            app.btnSkel2Mask.Tooltip = {'Create a binary mask from skeleton radius'};
            app.btnSkel2Mask.Text = 'Create Mask';
            app.btnSkelSave = uibutton(app.SkeletonTab, 'push');
            app.btnSkelSave.ButtonPushedFcn = createCallbackFcn(app, @btnSkelSaveButtonPushed, true);
            app.btnSkelSave.Icon = '48px-save-skel.png';
            app.btnSkelSave.Enable = 'off';
            app.btnSkelSave.Tooltip = {'Save current skeleton to disk'};
            app.btnSkelSave.Text = 'Save';
            app.btnSkelReflectX = uibutton(app.SkeletonTab, 'push');
            app.btnSkelReflectX.ButtonPushedFcn = createCallbackFcn(app, @btnSkelReflectXButtonPushed, true);
            app.btnSkelReflectX.Icon = '48px-MoleculeReflectX.png';
            app.btnSkelReflectX.Enable = 'off';
            app.btnSkelReflectX.Tooltip = {'Reflect skeleton coordinates along X axis'};
            app.btnSkelReflectX.Text = 'Reflect X';
            app.btnSkelReflectY = uibutton(app.SkeletonTab, 'push');
            app.btnSkelReflectY.ButtonPushedFcn = createCallbackFcn(app, @btnSkelReflectYButtonPushed, true);
            app.btnSkelReflectY.Icon = '48px-MoleculeReflectY.png';
            app.btnSkelReflectY.Enable = 'off';
            app.btnSkelReflectY.Tooltip = {'Reflect skeleton coordinates along Y axis'};
            app.btnSkelReflectY.Text = 'Reflect Y';
            app.btnSkelReflectZ = uibutton(app.SkeletonTab, 'push');
            app.btnSkelReflectZ.ButtonPushedFcn = createCallbackFcn(app, @btnSkelReflectZButtonPushed, true);
            app.btnSkelReflectZ.Icon = '48px-MoleculeReflectZ.png';
            app.btnSkelReflectZ.Enable = 'off';
            app.btnSkelReflectZ.Tooltip = {'Reflect skeleton coordinates along Z axis'};
            app.btnSkelReflectZ.Text = 'Reflect Z';
            app.btnSkelReset = uibutton(app.SkeletonTab, 'push');
            app.btnSkelReset.ButtonPushedFcn = createCallbackFcn(app, @btnSkelResetButtonPushed, true);
            app.btnSkelReset.Icon = '48px-refresh.png';
            app.btnSkelReset.Enable = 'off';
            app.btnSkelReset.Tooltip = {'Reset skeleton to the initial version'};
            app.btnSkelReset.Text = 'Reset';
            app.btnSkelInfo = uibutton(app.SkeletonTab, 'push');
            app.btnSkelInfo.ButtonPushedFcn = createCallbackFcn(app, @btnSkelInfoButtonPushed, true);
            app.btnSkelInfo.Icon = '48px-info.png';
            app.btnSkelInfo.Enable = 'off';
            app.btnSkelInfo.Tooltip = {'Show statistics of the current skeleton'};
            app.btnSkelInfo.Text = 'Info';
            app.btnSkelRecalibrate = uibutton(app.SkeletonTab, 'push');
            app.btnSkelRecalibrate.ButtonPushedFcn = createCallbackFcn(app, @btnSkelRecalibrateButtonPushed, true);
            app.btnSkelRecalibrate.Icon = '48px-skel-recalibrate.png';
            app.btnSkelRecalibrate.Enable = 'off';
            app.btnSkelRecalibrate.Tooltip = {'Change calibration of skeleton'};
            app.btnSkelRecalibrate.Text = 'Recalibrate';
            app.btnSkelResize = uibutton(app.SkeletonTab, 'push');
            app.btnSkelResize.ButtonPushedFcn = createCallbackFcn(app, @btnSkelResizeButtonPushed, true);
            app.btnSkelResize.Icon = '48px-skel-resize.png';
            app.btnSkelResize.Enable = 'off';
            app.btnSkelResize.Tooltip = {'Rescale pixels position maintaining resolution'};
            app.btnSkelResize.Text = 'Resize';
            app.btnSkelBinary = uibutton(app.SkeletonTab, 'push');
            app.btnSkelBinary.ButtonPushedFcn = createCallbackFcn(app, @btnSkelBinaryButtonPushed, true);
            app.btnSkelBinary.Icon = '48px-save-skel.png';
            app.btnSkelBinary.Enable = 'off';
            app.btnSkelBinary.Tooltip = {'Save current skeleton to disk'};
            app.btnSkelBinary.Text = 'Skeletonize';
            app.UIAxesSkel = uiaxes(app.SkeletonTab);
            app.AutomateTab = uitab(app.TabGroup);
            app.AutomateTab.Title = 'Automate';
            app.ProcessmultiplemasksPanel = uipanel(app.AutomateTab);
            app.ProcessmultiplemasksPanel.Title = 'Process multiple masks';
            app.btnBatchFolder = uibutton(app.ProcessmultiplemasksPanel, 'push');
            app.btnBatchFolder.ButtonPushedFcn = createCallbackFcn(app, @btnBatchFolderButtonPushed, true);
            app.btnBatchFolder.Icon = '48px-HomeFolder.png';
            app.btnBatchFolder.Tooltip = {'Select root folder'};
            app.btnBatchFolder.Text = 'Folder';
            app.btnBatchProcess = uibutton(app.ProcessmultiplemasksPanel, 'push');
            app.btnBatchProcess.ButtonPushedFcn = createCallbackFcn(app, @btnBatchProcessButtonPushed, true);
            app.btnBatchProcess.Icon = '48px-spreadsheet.png';
            app.btnBatchProcess.Tooltip = {'Process images matching pattern'};
            app.btnBatchProcess.Text = {'Process'; 'Batch'};
            app.chkBatchSubfolders = uicheckbox(app.ProcessmultiplemasksPanel);
            app.chkBatchSubfolders.Text = 'Search and process images in subfolders';
            app.chkBatchSubfolders.Value = true;
            app.MaskimagesLabel = uilabel(app.ProcessmultiplemasksPanel);
            app.MaskimagesLabel.Text = 'Mask images:';
            app.txtBatchMaskFilesPattern = uieditfield(app.ProcessmultiplemasksPanel, 'text');
            app.txtBatchMaskFilesPattern.Value = '*mask.tif';
            app.BatchoperationDropDownLabel = uilabel(app.ProcessmultiplemasksPanel);
            app.BatchoperationDropDownLabel.Text = 'Batch operation';
            app.cmbBatchOperation = uidropdown(app.ProcessmultiplemasksPanel);
            app.cmbBatchOperation.Items = {'Measure size', 'Measure intensity', 'Density by depth', 'Keep largest object'};
            app.cmbBatchOperation.ValueChangedFcn = createCallbackFcn(app, @cmbBatchOperationValueChanged, true);
            app.cmbBatchOperation.Value = 'Measure size';
            app.RawimagesLabel = uilabel(app.ProcessmultiplemasksPanel);
            app.RawimagesLabel.Enable = 'off';
            app.RawimagesLabel.Text = 'Raw images:';
            app.txtBatchRawFilesPattern = uieditfield(app.ProcessmultiplemasksPanel, 'text');
            app.txtBatchRawFilesPattern.Enable = 'off';
            app.txtBatchRawFilesPattern.Value = '*rawsignal.tif';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.lblAppName = uilabel(app.AboutTab);
            app.lblAppName.Text = 'VolumeCut';
            app.lblVersion = uilabel(app.AboutTab);
            app.lblVersion.Text = 'Version xx.yy.zz';
            app.Copyright20172020LucaDellaSantinaLabel = uilabel(app.AboutTab);
            app.Copyright20172020LucaDellaSantinaLabel.Text = 'Copyright 2017-2020 Luca Della Santina';
            app.TextArea = uitextarea(app.AboutTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'GNU GENERAL PUBLIC LICENSE'; 'Version 3'; ' 29 June 2007'; ''; 'Copyright © 2007 Free Software Foundation'; ' Inc. <https://fsf.org/>'; ''; 'Everyone is permitted to copy and distribute verbatim copies of this license document'; ' but changing it is not allowed.'; ''; 'Preamble'; 'The GNU General Public License is a free'; ' copyleft license for software and other kinds of works.'; ''; 'The licenses for most software and other practical works are designed to take away your freedom to share and change the works. By contrast'; ' the GNU General Public License is intended to guarantee your freedom to share and change all versions of a program--to make sure it remains free software for all its users. We'; ' the Free Software Foundation'; ' use the GNU General Public License for most of our software'; ' it applies also to any other work released this way by its authors. You can apply it to your programs'; ' too.'; ''; 'When we speak of free software'; ' we are referring to freedom'; ' not price. Our General Public Licenses are designed to make sure that you have the freedom to distribute copies of free software (and charge for them if you wish)'; ' that you receive source code or can get it if you want it'; ' that you can change the software or use pieces of it in new free programs'; ' and that you know you can do these things.'; ''; 'To protect your rights'; ' we need to prevent others from denying you these rights or asking you to surrender the rights. Therefore'; ' you have certain responsibilities if you distribute copies of the software'; ' or if you modify it: responsibilities to respect the freedom of others.'; ''; 'For example'; ' if you distribute copies of such a program'; ' whether gratis or for a fee'; ' you must pass on to the recipients the same freedoms that you received. You must make sure that they'; ' too'; ' receive or can get the source code. And you must show them these terms so they know their rights.'; ''; 'Developers that use the GNU GPL protect your rights with two steps: (1) assert copyright on the software'; ' and (2) offer you this License giving you legal permission to copy'; ' distribute and/or modify it.'; ''; 'For the developers'' and authors'' protection'; ' the GPL clearly explains that there is no warranty for this free software. For both users'' and authors'' sake'; ' the GPL requires that modified versions be marked as changed'; ' so that their problems will not be attributed erroneously to authors of previous versions.'; ''; 'Some devices are designed to deny users access to install or run modified versions of the software inside them'; ' although the manufacturer can do so. This is fundamentally incompatible with the aim of protecting users'' freedom to change the software. The systematic pattern of such abuse occurs in the area of products for individuals to use'; ' which is precisely where it is most unacceptable. Therefore'; ' we have designed this version of the GPL to prohibit the practice for those products. If such problems arise substantially in other domains'; ' we stand ready to extend this provision to those domains in future versions of the GPL'; ' as needed to protect the freedom of users.'; ''; 'Finally'; ' every program is threatened constantly by software patents. States should not allow patents to restrict development and use of software on general-purpose computers'; ' but in those that do'; ' we wish to avoid the special danger that patents applied to a free program could make it effectively proprietary. To prevent this'; ' the GPL assures that patents cannot be used to render the program non-free.'; ''; 'The precise terms and conditions for copying'; ' distribution and modification follow.'; ''; 'TERMS AND CONDITIONS'; '0. Definitions.'; '“This License” refers to version 3 of the GNU General Public License.'; ''; '“Copyright” also means copyright-like laws that apply to other kinds of works'; ' such as semiconductor masks.'; ''; '“The Program” refers to any copyrightable work licensed under this License. Each licensee is addressed as “you”. “Licensees” and “recipients” may be individuals or organizations.'; ''; 'To “modify” a work means to copy from or adapt all or part of the work in a fashion requiring copyright permission'; ' other than the making of an exact copy. The resulting work is called a “modified version” of the earlier work or a work “based on” the earlier work.'; ''; 'A “covered work” means either the unmodified Program or a work based on the Program.'; ''; 'To “propagate” a work means to do anything with it that'; ' without permission'; ' would make you directly or secondarily liable for infringement under applicable copyright law'; ' except executing it on a computer or modifying a private copy. Propagation includes copying'; ' distribution (with or without modification)'; ' making available to the public'; ' and in some countries other activities as well.'; ''; 'To “convey” a work means any kind of propagation that enables other parties to make or receive copies. Mere interaction with a user through a computer network'; ' with no transfer of a copy'; ' is not conveying.'; ''; 'An interactive user interface displays “Appropriate Legal Notices” to the extent that it includes a convenient and prominently visible feature that (1) displays an appropriate copyright notice'; ' and (2) tells the user that there is no warranty for the work (except to the extent that warranties are provided)'; ' that licensees may convey the work under this License'; ' and how to view a copy of this License. If the interface presents a list of user commands or options'; ' such as a menu'; ' a prominent item in the list meets this criterion.'; ''; '1. Source Code.'; 'The “source code” for a work means the preferred form of the work for making modifications to it. “Object code” means any non-source form of a work.'; ''; 'A “Standard Interface” means an interface that either is an official standard defined by a recognized standards body'; ' or'; ' in the case of interfaces specified for a particular programming language'; ' one that is widely used among developers working in that language.'; ''; 'The “System Libraries” of an executable work include anything'; ' other than the work as a whole'; ' that (a) is included in the normal form of packaging a Major Component'; ' but which is not part of that Major Component'; ' and (b) serves only to enable use of the work with that Major Component'; ' or to implement a Standard Interface for which an implementation is available to the public in source code form. A “Major Component”'; ' in this context'; ' means a major essential component (kernel'; ' window system'; ' and so on) of the specific operating system (if any) on which the executable work runs'; ' or a compiler used to produce the work'; ' or an object code interpreter used to run it.'; ''; 'The “Corresponding Source” for a work in object code form means all the source code needed to generate'; ' install'; ' and (for an executable work) run the object code and to modify the work'; ' including scripts to control those activities. However'; ' it does not include the work''s System Libraries'; ' or general-purpose tools or generally available free programs which are used unmodified in performing those activities but which are not part of the work. For example'; ' Corresponding Source includes interface definition files associated with source files for the work'; ' and the source code for shared libraries and dynamically linked subprograms that the work is specifically designed to require'; ' such as by intimate data communication or control flow between those subprograms and other parts of the work.'; ''; 'The Corresponding Source need not include anything that users can regenerate automatically from other parts of the Corresponding Source.'; ''; 'The Corresponding Source for a work in source code form is that same work.'; ''; '2. Basic Permissions.'; 'All rights granted under this License are granted for the term of copyright on the Program'; ' and are irrevocable provided the stated conditions are met. This License explicitly affirms your unlimited permission to run the unmodified Program. The output from running a covered work is covered by this License only if the output'; ' given its content'; ' constitutes a covered work. This License acknowledges your rights of fair use or other equivalent'; ' as provided by copyright law.'; ''; 'You may make'; ' run and propagate covered works that you do not convey'; ' without conditions so long as your license otherwise remains in force. You may convey covered works to others for the sole purpose of having them make modifications exclusively for you'; ' or provide you with facilities for running those works'; ' provided that you comply with the terms of this License in conveying all material for which you do not control copyright. Those thus making or running the covered works for you must do so exclusively on your behalf'; ' under your direction and control'; ' on terms that prohibit them from making any copies of your copyrighted material outside their relationship with you.'; ''; 'Conveying under any other circumstances is permitted solely under the conditions stated below. Sublicensing is not allowed'; ' section 10 makes it unnecessary.'; ''; '3. Protecting Users'' Legal Rights From Anti-Circumvention Law.'; 'No covered work shall be deemed part of an effective technological measure under any applicable law fulfilling obligations under article 11 of the WIPO copyright treaty adopted on 20 December 1996'; ' or similar laws prohibiting or restricting circumvention of such measures.'; ''; 'When you convey a covered work'; ' you waive any legal power to forbid circumvention of technological measures to the extent such circumvention is effected by exercising rights under this License with respect to the covered work'; ' and you disclaim any intention to limit operation or modification of the work as a means of enforcing'; ' against the work''s users'; ' your or third parties'' legal rights to forbid circumvention of technological measures.'; ''; '4. Conveying Verbatim Copies.'; 'You may convey verbatim copies of the Program''s source code as you receive it'; ' in any medium'; ' provided that you conspicuously and appropriately publish on each copy an appropriate copyright notice'; ' keep intact all notices stating that this License and any non-permissive terms added in accord with section 7 apply to the code'; ' keep intact all notices of the absence of any warranty'; ' and give all recipients a copy of this License along with the Program.'; ''; 'You may charge any price or no price for each copy that you convey'; ' and you may offer support or warranty protection for a fee.'; ''; '5. Conveying Modified Source Versions.'; 'You may convey a work based on the Program'; ' or the modifications to produce it from the Program'; ' in the form of source code under the terms of section 4'; ' provided that you also meet all of these conditions:'; ''; 'a) The work must carry prominent notices stating that you modified it'; ' and giving a relevant date.'; 'b) The work must carry prominent notices stating that it is released under this License and any conditions added under section 7. This requirement modifies the requirement in section 4 to “keep intact all notices”.'; 'c) You must license the entire work'; ' as a whole'; ' under this License to anyone who comes into possession of a copy. This License will therefore apply'; ' along with any applicable section 7 additional terms'; ' to the whole of the work'; ' and all its parts'; ' regardless of how they are packaged. This License gives no permission to license the work in any other way'; ' but it does not invalidate such permission if you have separately received it.'; 'd) If the work has interactive user interfaces'; ' each must display Appropriate Legal Notices'; ' however'; ' if the Program has interactive interfaces that do not display Appropriate Legal Notices'; ' your work need not make them do so.'; 'A compilation of a covered work with other separate and independent works'; ' which are not by their nature extensions of the covered work'; ' and which are not combined with it such as to form a larger program'; ' in or on a volume of a storage or distribution medium'; ' is called an “aggregate” if the compilation and its resulting copyright are not used to limit the access or legal rights of the compilation''s users beyond what the individual works permit. Inclusion of a covered work in an aggregate does not cause this License to apply to the other parts of the aggregate.'; ''; '6. Conveying Non-Source Forms.'; 'You may convey a covered work in object code form under the terms of sections 4 and 5'; ' provided that you also convey the machine-readable Corresponding Source under the terms of this License'; ' in one of these ways:'; ''; 'a) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by the Corresponding Source fixed on a durable physical medium customarily used for software interchange.'; 'b) Convey the object code in'; ' or embodied in'; ' a physical product (including a physical distribution medium)'; ' accompanied by a written offer'; ' valid for at least three years and valid for as long as you offer spare parts or customer support for that product model'; ' to give anyone who possesses the object code either (1) a copy of the Corresponding Source for all the software in the product that is covered by this License'; ' on a durable physical medium customarily used for software interchange'; ' for a price no more than your reasonable cost of physically performing this conveying of source'; ' or (2) access to copy the Corresponding Source from a network server at no charge.'; 'c) Convey individual copies of the object code with a copy of the written offer to provide the Corresponding Source. This alternative is allowed only occasionally and noncommercially'; ' and only if you received the object code with such an offer'; ' in accord with subsection 6b.'; 'd) Convey the object code by offering access from a designated place (gratis or for a charge)'; ' and offer equivalent access to the Corresponding Source in the same way through the same place at no further charge. You need not require recipients to copy the Corresponding Source along with the object code. If the place to copy the object code is a network server'; ' the Corresponding Source may be on a different server (operated by you or a third party) that supports equivalent copying facilities'; ' provided you maintain clear directions next to the object code saying where to find the Corresponding Source. Regardless of what server hosts the Corresponding Source'; ' you remain obligated to ensure that it is available for as long as needed to satisfy these requirements.'; 'e) Convey the object code using peer-to-peer transmission'; ' provided you inform other peers where the object code and Corresponding Source of the work are being offered to the general public at no charge under subsection 6d.'; 'A separable portion of the object code'; ' whose source code is excluded from the Corresponding Source as a System Library'; ' need not be included in conveying the object code work.'; ''; 'A “User Product” is either (1) a “consumer product”'; ' which means any tangible personal property which is normally used for personal'; ' family'; ' or household purposes'; ' or (2) anything designed or sold for incorporation into a dwelling. In determining whether a product is a consumer product'; ' doubtful cases shall be resolved in favor of coverage. For a particular product received by a particular user'; ' “normally used” refers to a typical or common use of that class of product'; ' regardless of the status of the particular user or of the way in which the particular user actually uses'; ' or expects or is expected to use'; ' the product. A product is a consumer product regardless of whether the product has substantial commercial'; ' industrial or non-consumer uses'; ' unless such uses represent the only significant mode of use of the product.'; ''; '“Installation Information” for a User Product means any methods'; ' procedures'; ' authorization keys'; ' or other information required to install and execute modified versions of a covered work in that User Product from a modified version of its Corresponding Source. The information must suffice to ensure that the continued functioning of the modified object code is in no case prevented or interfered with solely because modification has been made.'; ''; 'If you convey an object code work under this section in'; ' or with'; ' or specifically for use in'; ' a User Product'; ' and the conveying occurs as part of a transaction in which the right of possession and use of the User Product is transferred to the recipient in perpetuity or for a fixed term (regardless of how the transaction is characterized)'; ' the Corresponding Source conveyed under this section must be accompanied by the Installation Information. But this requirement does not apply if neither you nor any third party retains the ability to install modified object code on the User Product (for example'; ' the work has been installed in ROM).'; ''; 'The requirement to provide Installation Information does not include a requirement to continue to provide support service'; ' warranty'; ' or updates for a work that has been modified or installed by the recipient'; ' or for the User Product in which it has been modified or installed. Access to a network may be denied when the modification itself materially and adversely affects the operation of the network or violates the rules and protocols for communication across the network.'; ''; 'Corresponding Source conveyed'; ' and Installation Information provided'; ' in accord with this section must be in a format that is publicly documented (and with an implementation available to the public in source code form)'; ' and must require no special password or key for unpacking'; ' reading or copying.'; ''; '7. Additional Terms.'; '“Additional permissions” are terms that supplement the terms of this License by making exceptions from one or more of its conditions. Additional permissions that are applicable to the entire Program shall be treated as though they were included in this License'; ' to the extent that they are valid under applicable law. If additional permissions apply only to part of the Program'; ' that part may be used separately under those permissions'; ' but the entire Program remains governed by this License without regard to the additional permissions.'; ''; 'When you convey a copy of a covered work'; ' you may at your option remove any additional permissions from that copy'; ' or from any part of it. (Additional permissions may be written to require their own removal in certain cases when you modify the work.) You may place additional permissions on material'; ' added by you to a covered work'; ' for which you have or can give appropriate copyright permission.'; ''; 'Notwithstanding any other provision of this License'; ' for material you add to a covered work'; ' you may (if authorized by the copyright holders of that material) supplement the terms of this License with terms:'; ''; 'a) Disclaiming warranty or limiting liability differently from the terms of sections 15 and 16 of this License'; ' or'; 'b) Requiring preservation of specified reasonable legal notices or author attributions in that material or in the Appropriate Legal Notices displayed by works containing it'; ' or'; 'c) Prohibiting misrepresentation of the origin of that material'; ' or requiring that modified versions of such material be marked in reasonable ways as different from the original version'; ' or'; 'd) Limiting the use for publicity purposes of names of licensors or authors of the material'; ' or'; 'e) Declining to grant rights under trademark law for use of some trade names'; ' trademarks'; ' or service marks'; ' or'; 'f) Requiring indemnification of licensors and authors of that material by anyone who conveys the material (or modified versions of it) with contractual assumptions of liability to the recipient'; ' for any liability that these contractual assumptions directly impose on those licensors and authors.'; 'All other non-permissive additional terms are considered “further restrictions” within the meaning of section 10. If the Program as you received it'; ' or any part of it'; ' contains a notice stating that it is governed by this License along with a term that is a further restriction'; ' you may remove that term. If a license document contains a further restriction but permits relicensing or conveying under this License'; ' you may add to a covered work material governed by the terms of that license document'; ' provided that the further restriction does not survive such relicensing or conveying.'; ''; 'If you add terms to a covered work in accord with this section'; ' you must place'; ' in the relevant source files'; ' a statement of the additional terms that apply to those files'; ' or a notice indicating where to find the applicable terms.'; ''; 'Additional terms'; ' permissive or non-permissive'; ' may be stated in the form of a separately written license'; ' or stated as exceptions'; ' the above requirements apply either way.'; ''; '8. Termination.'; 'You may not propagate or modify a covered work except as expressly provided under this License. Any attempt otherwise to propagate or modify it is void'; ' and will automatically terminate your rights under this License (including any patent licenses granted under the third paragraph of section 11).'; ''; 'However'; ' if you cease all violation of this License'; ' then your license from a particular copyright holder is reinstated (a) provisionally'; ' unless and until the copyright holder explicitly and finally terminates your license'; ' and (b) permanently'; ' if the copyright holder fails to notify you of the violation by some reasonable means prior to 60 days after the cessation.'; ''; 'Moreover'; ' your license from a particular copyright holder is reinstated permanently if the copyright holder notifies you of the violation by some reasonable means'; ' this is the first time you have received notice of violation of this License (for any work) from that copyright holder'; ' and you cure the violation prior to 30 days after your receipt of the notice.'; ''; 'Termination of your rights under this section does not terminate the licenses of parties who have received copies or rights from you under this License. If your rights have been terminated and not permanently reinstated'; ' you do not qualify to receive new licenses for the same material under section 10.'; ''; '9. Acceptance Not Required for Having Copies.'; 'You are not required to accept this License in order to receive or run a copy of the Program. Ancillary propagation of a covered work occurring solely as a consequence of using peer-to-peer transmission to receive a copy likewise does not require acceptance. However'; ' nothing other than this License grants you permission to propagate or modify any covered work. These actions infringe copyright if you do not accept this License. Therefore'; ' by modifying or propagating a covered work'; ' you indicate your acceptance of this License to do so.'; ''; '10. Automatic Licensing of Downstream Recipients.'; 'Each time you convey a covered work'; ' the recipient automatically receives a license from the original licensors'; ' to run'; ' modify and propagate that work'; ' subject to this License. You are not responsible for enforcing compliance by third parties with this License.'; ''; 'An “entity transaction” is a transaction transferring control of an organization'; ' or substantially all assets of one'; ' or subdividing an organization'; ' or merging organizations. If propagation of a covered work results from an entity transaction'; ' each party to that transaction who receives a copy of the work also receives whatever licenses to the work the party''s predecessor in interest had or could give under the previous paragraph'; ' plus a right to possession of the Corresponding Source of the work from the predecessor in interest'; ' if the predecessor has it or can get it with reasonable efforts.'; ''; 'You may not impose any further restrictions on the exercise of the rights granted or affirmed under this License. For example'; ' you may not impose a license fee'; ' royalty'; ' or other charge for exercise of rights granted under this License'; ' and you may not initiate litigation (including a cross-claim or counterclaim in a lawsuit) alleging that any patent claim is infringed by making'; ' using'; ' selling'; ' offering for sale'; ' or importing the Program or any portion of it.'; ''; '11. Patents.'; 'A “contributor” is a copyright holder who authorizes use under this License of the Program or a work on which the Program is based. The work thus licensed is called the contributor''s “contributor version”.'; ''; 'A contributor''s “essential patent claims” are all patent claims owned or controlled by the contributor'; ' whether already acquired or hereafter acquired'; ' that would be infringed by some manner'; ' permitted by this License'; ' of making'; ' using'; ' or selling its contributor version'; ' but do not include claims that would be infringed only as a consequence of further modification of the contributor version. For purposes of this definition'; ' “control” includes the right to grant patent sublicenses in a manner consistent with the requirements of this License.'; ''; 'Each contributor grants you a non-exclusive'; ' worldwide'; ' royalty-free patent license under the contributor''s essential patent claims'; ' to make'; ' use'; ' sell'; ' offer for sale'; ' import and otherwise run'; ' modify and propagate the contents of its contributor version.'; ''; 'In the following three paragraphs'; ' a “patent license” is any express agreement or commitment'; ' however denominated'; ' not to enforce a patent (such as an express permission to practice a patent or covenant not to sue for patent infringement). To “grant” such a patent license to a party means to make such an agreement or commitment not to enforce a patent against the party.'; ''; 'If you convey a covered work'; ' knowingly relying on a patent license'; ' and the Corresponding Source of the work is not available for anyone to copy'; ' free of charge and under the terms of this License'; ' through a publicly available network server or other readily accessible means'; ' then you must either (1) cause the Corresponding Source to be so available'; ' or (2) arrange to deprive yourself of the benefit of the patent license for this particular work'; ' or (3) arrange'; ' in a manner consistent with the requirements of this License'; ' to extend the patent license to downstream recipients. “Knowingly relying” means you have actual knowledge that'; ' but for the patent license'; ' your conveying the covered work in a country'; ' or your recipient''s use of the covered work in a country'; ' would infringe one or more identifiable patents in that country that you have reason to believe are valid.'; ''; 'If'; ' pursuant to or in connection with a single transaction or arrangement'; ' you convey'; ' or propagate by procuring conveyance of'; ' a covered work'; ' and grant a patent license to some of the parties receiving the covered work authorizing them to use'; ' propagate'; ' modify or convey a specific copy of the covered work'; ' then the patent license you grant is automatically extended to all recipients of the covered work and works based on it.'; ''; 'A patent license is “discriminatory” if it does not include within the scope of its coverage'; ' prohibits the exercise of'; ' or is conditioned on the non-exercise of one or more of the rights that are specifically granted under this License. You may not convey a covered work if you are a party to an arrangement with a third party that is in the business of distributing software'; ' under which you make payment to the third party based on the extent of your activity of conveying the work'; ' and under which the third party grants'; ' to any of the parties who would receive the covered work from you'; ' a discriminatory patent license (a) in connection with copies of the covered work conveyed by you (or copies made from those copies)'; ' or (b) primarily for and in connection with specific products or compilations that contain the covered work'; ' unless you entered into that arrangement'; ' or that patent license was granted'; ' prior to 28 March 2007.'; ''; 'Nothing in this License shall be construed as excluding or limiting any implied license or other defenses to infringement that may otherwise be available to you under applicable patent law.'; ''; '12. No Surrender of Others'' Freedom.'; 'If conditions are imposed on you (whether by court order'; ' agreement or otherwise) that contradict the conditions of this License'; ' they do not excuse you from the conditions of this License. If you cannot convey a covered work so as to satisfy simultaneously your obligations under this License and any other pertinent obligations'; ' then as a consequence you may not convey it at all. For example'; ' if you agree to terms that obligate you to collect a royalty for further conveying from those to whom you convey the Program'; ' the only way you could satisfy both those terms and this License would be to refrain entirely from conveying the Program.'; ''; '13. Use with the GNU Affero General Public License.'; 'Notwithstanding any other provision of this License'; ' you have permission to link or combine any covered work with a work licensed under version 3 of the GNU Affero General Public License into a single combined work'; ' and to convey the resulting work. The terms of this License will continue to apply to the part which is the covered work'; ' but the special requirements of the GNU Affero General Public License'; ' section 13'; ' concerning interaction through a network will apply to the combination as such.'; ''; '14. Revised Versions of this License.'; 'The Free Software Foundation may publish revised and/or new versions of the GNU General Public License from time to time. Such new versions will be similar in spirit to the present version'; ' but may differ in detail to address new problems or concerns.'; ''; 'Each version is given a distinguishing version number. If the Program specifies that a certain numbered version of the GNU General Public License “or any later version” applies to it'; ' you have the option of following the terms and conditions either of that numbered version or of any later version published by the Free Software Foundation. If the Program does not specify a version number of the GNU General Public License'; ' you may choose any version ever published by the Free Software Foundation.'; ''; 'If the Program specifies that a proxy can decide which future versions of the GNU General Public License can be used'; ' that proxy''s public statement of acceptance of a version permanently authorizes you to choose that version for the Program.'; ''; 'Later license versions may give you additional or different permissions. However'; ' no additional obligations are imposed on any author or copyright holder as a result of your choosing to follow a later version.'; ''; '15. Disclaimer of Warranty.'; 'THERE IS NO WARRANTY FOR THE PROGRAM'; ' TO THE EXTENT PERMITTED BY APPLICABLE LAW. EXCEPT WHEN OTHERWISE STATED IN WRITING THE COPYRIGHT HOLDERS AND/OR OTHER PARTIES PROVIDE THE PROGRAM “AS IS” WITHOUT WARRANTY OF ANY KIND'; ' EITHER EXPRESSED OR IMPLIED'; ' INCLUDING'; ' BUT NOT LIMITED TO'; ' THE IMPLIED WARRANTIES OF MERCHANTABILITY AND FITNESS FOR A PARTICULAR PURPOSE. THE ENTIRE RISK AS TO THE QUALITY AND PERFORMANCE OF THE PROGRAM IS WITH YOU. SHOULD THE PROGRAM PROVE DEFECTIVE'; ' YOU ASSUME THE COST OF ALL NECESSARY SERVICING'; ' REPAIR OR CORRECTION.'; ''; '16. Limitation of Liability.'; 'IN NO EVENT UNLESS REQUIRED BY APPLICABLE LAW OR AGREED TO IN WRITING WILL ANY COPYRIGHT HOLDER'; ' OR ANY OTHER PARTY WHO MODIFIES AND/OR CONVEYS THE PROGRAM AS PERMITTED ABOVE'; ' BE LIABLE TO YOU FOR DAMAGES'; ' INCLUDING ANY GENERAL'; ' SPECIAL'; ' INCIDENTAL OR CONSEQUENTIAL DAMAGES ARISING OUT OF THE USE OR INABILITY TO USE THE PROGRAM (INCLUDING BUT NOT LIMITED TO LOSS OF DATA OR DATA BEING RENDERED INACCURATE OR LOSSES SUSTAINED BY YOU OR THIRD PARTIES OR A FAILURE OF THE PROGRAM TO OPERATE WITH ANY OTHER PROGRAMS)'; ' EVEN IF SUCH HOLDER OR OTHER PARTY HAS BEEN ADVISED OF THE POSSIBILITY OF SUCH DAMAGES.'; ''; '17. Interpretation of Sections 15 and 16.'; 'If the disclaimer of warranty and limitation of liability provided above cannot be given local legal effect according to their terms'; ' reviewing courts shall apply local law that most closely approximates an absolute waiver of all civil liability in connection with the Program'; ' unless a warranty or assumption of liability accompanies a copy of the Program in return for a fee.'; ''; 'END OF TERMS AND CONDITIONS'; ''; 'How to Apply These Terms to Your New Programs'; 'If you develop a new program'; ' and you want it to be of the greatest possible use to the public'; ' the best way to achieve this is to make it free software which everyone can redistribute and change under these terms.'; ''; 'To do so'; ' attach the following notices to the program. It is safest to attach them to the start of each source file to most effectively state the exclusion of warranty'; ' and each file should have at least the “copyright” line and a pointer to where the full notice is found.'; ''; '    <one line to give the program''s name and a brief idea of what it does.>'; '    Copyright (C) <year>  <name of author>'; ''; '    This program is free software: you can redistribute it and/or modify'; '    it under the terms of the GNU General Public License as published by'; '    the Free Software Foundation'; ' either version 3 of the License'; ' or'; '    (at your option) any later version.'; ''; '    This program is distributed in the hope that it will be useful'; ''; '    but WITHOUT ANY WARRANTY'; ' without even the implied warranty of'; '    MERCHANTABILITY or FITNESS FOR A PARTICULAR PURPOSE.  See the'; '    GNU General Public License for more details.'; ''; '    You should have received a copy of the GNU General Public License'; '    along with this program.  If not'; ' see <https://www.gnu.org/licenses/>.'; 'Also add information on how to contact you by electronic and paper mail.'; ''; 'If the program does terminal interaction'; ' make it output a short notice like this when it starts in an interactive mode:'; ''; '    <program>  Copyright (C) <year>  <name of author>'; '    This program comes with ABSOLUTELY NO WARRANTY'; ' for details type `show w''.'; '    This is free software'; ' and you are welcome to redistribute it'; '    under certain conditions'; ' type `show c'' for details.'; 'The hypothetical commands `show w'' and `show c'' should show the appropriate parts of the General Public License. Of course'; ' your program''s commands might be different'; ' for a GUI interface'; ' you would use an “about box”.'; ''; 'You should also get your employer (if you work as a programmer) or school'; ' if any'; ' to sign a “copyright disclaimer” for the program'; ' if necessary. For more information on this'; ' and how to apply and follow the GNU GPL'; ' see <https://www.gnu.org/licenses/>.'; ''; 'The GNU General Public License does not permit incorporating your program into proprietary programs. If your program is a subroutine library'; ' you may consider it more useful to permit linking proprietary applications with the library. If this is what you want to do'; ' use the GNU Lesser General Public License instead of this License. But first'; ' please read <https://www.gnu.org/licenses/why-not-lgpl.html>.'};
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'app_icon.svg';
            % Show the figure after all components are created
            app.VolumeCutUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VolumeCut

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.VolumeCutUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.VolumeCutUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VolumeCutUIFigure)
        end
    end
end